classdef BCI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BCIProcessor                   matlab.ui.Figure
        Panel_2                        matlab.ui.container.Panel
        ProcessResults                 matlab.ui.container.Panel
        ResultsListBox                 matlab.ui.control.ListBox
        ResultsListBoxLabel            matlab.ui.control.Label
        FeaturesListBox                matlab.ui.control.ListBox
        FeaturesListBoxLabel           matlab.ui.control.Label
        PlotFeaturesCheckBox           matlab.ui.control.CheckBox
        PlotResultsCheckBox            matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        InfoTab                        matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        WhatsishappeningLabel          matlab.ui.control.Label
        PlotTab                        matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        DataTab                        matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        Panel                          matlab.ui.container.Panel
        FeaturePropertiesPanel         matlab.ui.container.Panel
        signaltype                     matlab.ui.container.Panel
        diffcheditfield                matlab.ui.control.EditField
        signaltypeDerivativeChannelsCheckBox  matlab.ui.control.CheckBox
        signaltypeSeconedDescreteDerivativeCheckBox  matlab.ui.control.CheckBox
        siganltypeDescreteDerivativeCheckBox  matlab.ui.control.CheckBox
        signaltypetimenormalCheckBox   matlab.ui.control.CheckBox
        signaltypeFastFourierTransformCheckBox  matlab.ui.control.CheckBox
        HowtoUseFeaturesButtonGroup    matlab.ui.container.ButtonGroup
        LastBestResultsButton          matlab.ui.control.RadioButton
        AllTogetherButton_2            matlab.ui.control.RadioButton
        SeparatelyButton               matlab.ui.control.RadioButton
        ProcessingparamLamp            matlab.ui.control.Lamp
        ProcessingParametersButton     matlab.ui.control.Button
        ReadyLamp                      matlab.ui.control.Lamp
        ReadyButton                    matlab.ui.control.StateButton
        SavingResultsLamp              matlab.ui.control.Lamp
        SavingResultsLampLabel         matlab.ui.control.Label
        PlotLamp                       matlab.ui.control.Lamp
        PlotLampLabel                  matlab.ui.control.Label
        ClassificationLamp             matlab.ui.control.Lamp
        ClassificationLampLabel        matlab.ui.control.Label
        FeatureExtractionLamp          matlab.ui.control.Lamp
        FeatureExtractionLampLabel     matlab.ui.control.Label
        PreprocessingLamp              matlab.ui.control.Lamp
        PreprocessingLampLabel         matlab.ui.control.Label
        FileLoadingLamp                matlab.ui.control.Lamp
        FileLoadingLampLabel           matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        turnonSwitch                   matlab.ui.control.ToggleSwitch
        StartLabel                     matlab.ui.control.Label
        WhattoDoButtonGroup            matlab.ui.container.ButtonGroup
        ExtractClassifiyButton         matlab.ui.control.RadioButton
        AllTogetherButton              matlab.ui.control.RadioButton
        ProcessAvailableResultsButton  matlab.ui.control.RadioButton
        ClassifyAvailableFeaturesButton  matlab.ui.control.RadioButton
        OnlyExtractFeatureButton       matlab.ui.control.RadioButton
        ClassifcatoinLabel             matlab.ui.control.Label
        BestAccuracyGauge              matlab.ui.control.Gauge
        selectrawfile                  matlab.ui.container.Panel
        saveDirLamp                    matlab.ui.control.Lamp
        savefile_2                     matlab.ui.control.Button
        savefolderLabel                matlab.ui.control.Label
        DirLamp                        matlab.ui.control.Lamp
        InfoButton                     matlab.ui.control.Button
        Choosefile                     matlab.ui.control.Button
        inputfilesLabel                matlab.ui.control.Label
        AvailableSamplesDropDown       matlab.ui.control.DropDown
        AvailableSamplesDropDownLabel  matlab.ui.control.Label
        features                       matlab.ui.container.Panel
        peakFunctionListBox            matlab.ui.control.ListBox
        FunctionsLabel                 matlab.ui.control.Label
        liapAllChannelsCheckBox        matlab.ui.control.CheckBox
        liapSingleChannelCheckBox      matlab.ui.control.CheckBox
        hurstAllChannelsCheckBox       matlab.ui.control.CheckBox
        hurstSingleChannelCheckBox     matlab.ui.control.CheckBox
        entropyAllChannelsCheckBox     matlab.ui.control.CheckBox
        entropySingleChannelCheckBox   matlab.ui.control.CheckBox
        entropyrfactor                 matlab.ui.control.NumericEditField
        rfactorLabel                   matlab.ui.control.Label
        entropymfactor                 matlab.ui.control.NumericEditField
        mfactorLabel                   matlab.ui.control.Label
        cspchannelsEditField           matlab.ui.control.EditField
        ChannelsEditFieldLabel         matlab.ui.control.Label
        psdTop3PeaksCheckBox           matlab.ui.control.CheckBox
        psdTopPeakCheckBox             matlab.ui.control.CheckBox
        psdCompleteCheckBox            matlab.ui.control.CheckBox
        CSPvarparam                    matlab.ui.control.NumericEditField
        NumberofVariancerowsEditFieldLabel  matlab.ui.control.Label
        higuchiCSPHiguchiCheckBox      matlab.ui.control.CheckBox
        higuchiAllChannelsCheckBox     matlab.ui.control.CheckBox
        higuchiSingleChannelCheckBox   matlab.ui.control.CheckBox
        LiapanuvExpCheckBox            matlab.ui.control.CheckBox
        PeakCheckBox                   matlab.ui.control.CheckBox
        HurstExponentCheckBox          matlab.ui.control.CheckBox
        EntropyEstimationCheckBox      matlab.ui.control.CheckBox
        PSDEstimationCheckBox          matlab.ui.control.CheckBox
        FractalDimenssionHiguchiCheckBox  matlab.ui.control.CheckBox
        CSPCommonSpatialPatternCheckBox  matlab.ui.control.CheckBox
        preprocess                     matlab.ui.container.Panel
        mspcafunction                  matlab.ui.control.DropDown
        FunctionDropDownLabel          matlab.ui.control.Label
        LevelEditField                 matlab.ui.control.NumericEditField
        LevelEditFieldLabel_2          matlab.ui.control.Label
        butterhighb                    matlab.ui.control.NumericEditField
        HighEditFieldLabel             matlab.ui.control.Label
        butterlowb                     matlab.ui.control.NumericEditField
        LowEditFieldLabel              matlab.ui.control.Label
        butterlevel                    matlab.ui.control.NumericEditField
        LevelEditFieldLabel            matlab.ui.control.Label
        ButterworthCheckBox            matlab.ui.control.CheckBox
        MSPCACheckBox                  matlab.ui.control.CheckBox
        classifiers                    matlab.ui.container.Panel
        KfoldCrossvalidationEditField  matlab.ui.control.NumericEditField
        KfoldCrossvalidationEditFieldLabel  matlab.ui.control.Label
        KernelEditField                matlab.ui.control.EditField
        KernelEditFieldLabel           matlab.ui.control.Label
        knnNumberEditField             matlab.ui.control.NumericEditField
        NeighbourLabel                 matlab.ui.control.Label
        LSTMDNNCheckBox                matlab.ui.control.CheckBox
        DCNNCheckBox                   matlab.ui.control.CheckBox
        SVMCheckBox                    matlab.ui.control.CheckBox
        KNNCheckBox                    matlab.ui.control.CheckBox
        LDACheckBox                    matlab.ui.control.CheckBox
        MahalCheckBox                  matlab.ui.control.CheckBox
        DataSelectionLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        feature
        eegProperties
        processingProperties
        descriptiontext

    end
    
    properties (Access = private)
        currentfeature % Description
        lastfeature
        siganltype
        infoApp % Description
        processingparamApp
        classifierlist
        bciresults
        flaginfo
        eegnoofFiles
        errortext
        file
        color
    end
    
    
    methods (Access = private)
        
        function firstbuild(app)
            app.eegProperties = eegproperties;
            app.processingProperties = processingproperties;
            app.TextArea.Value = 'Welcome to the BCI Processor. This Matlab App is designed and copyrighted by Behtom Adeli. Please Choose Parameters.';
            app.currentfeature = [];
            higuchi = struct('name','higuchi','singlech',app.higuchiSingleChannelCheckBox.Value,'allch',app.higuchiAllChannelsCheckBox.Value,'higcsp',app.higuchiCSPHiguchiCheckBox.Value,'signaltype',[],'active',false);
            csp = struct('name','csp','var',app.CSPvarparam.Value,'channels',app.cspchannelsEditField.Value,'signaltype',[],'active',false);
            psd = struct('name','psd','normal',app.psdCompleteCheckBox.Value,'top',app.psdTopPeakCheckBox.Value,'top3',app.psdTop3PeaksCheckBox.Value,'signaltype',[],'active',false);
            entropy = struct('name','entropy','singlech',app.entropySingleChannelCheckBox.Value,'allch',app.entropyAllChannelsCheckBox.Value,'mfactor',app.entropymfactor.Value,'rfactor',app.entropyrfactor.Value,'signaltype',[],'active',false);
            hurst = struct('name','hurst','singlech',app.hurstSingleChannelCheckBox.Value,'allch',app.hurstAllChannelsCheckBox.Value,'signaltype',[],'active',false);
            liap = struct('name','liap','singlech',app.liapSingleChannelCheckBox.Value,'allch',app.liapAllChannelsCheckBox.Value,'signaltype',[],'active',false);
            peak = getpeaklist(app);
            app.feature = struct('higuchi',higuchi,'csp',csp,'psd',psd,'entropy',entropy,'hurst',hurst,'liap',liap,'peak',peak); 
            app.classifierlist = struct('lda',app.LDACheckBox.Value,'mahal',app.MahalCheckBox.Value,'knn',app.KNNCheckBox.Value,'svm',app.SVMCheckBox.Value,'dcnn',app.DCNNCheckBox.Value,'lstmdnn',app.LSTMDNNCheckBox.Value,'svmkernel',app.KernelEditField.Value,'knnneighbour',app.knnNumberEditField.Value);
            app.color = struct('red',[1,0,0],'green', [0,1,0],'yellow',[1,1,0],'orange', [1,0.41,0.16],'gray',[0.65,0.65,0.65],'notokred',[0.98, 0.53, 0.53],'okgreen',[0.78,0.97,0.72]);
        end
       
        
        function signaltyp = getsignaltypes(app)
            normal = app.signaltypetimenormalCheckBox.Value ;
            DD = app.siganltypeDescreteDerivativeCheckBox.Value;
            DD2 = app.signaltypeSeconedDescreteDerivativeCheckBox.Value;
            fft = app.signaltypeFastFourierTransformCheckBox.Value;
            diffch = app.signaltypeDerivativeChannelsCheckBox.Value;
            signaltyp = struct('normal',normal,'DD',DD,'DD2',DD2,'fft',fft,'diffch',diffch);
        end
        
        function signal_typ = updatesignaltypes(app) 
            signal_typ = app.feature.(app.currentfeature).signaltype;
            if isempty (signal_typ)
                signal_typ = clearsignaltype(app);
            end
            if  strcmp(app.signaltypetimenormalCheckBox.Enable , 'off')
                app.signaltypetimenormalCheckBox.Enable                  = true ;
                app.siganltypeDescreteDerivativeCheckBox.Enable          = true ;
                app.signaltypeSeconedDescreteDerivativeCheckBox.Enable   = true ; 
                app.signaltypeFastFourierTransformCheckBox.Enable        = true ;
                app.signaltypeDerivativeChannelsCheckBox.Enable          = true ;
            end
            app.signaltypetimenormalCheckBox.Value                  = signal_typ.normal;
            app.siganltypeDescreteDerivativeCheckBox.Value          = signal_typ.DD ;
            app.signaltypeSeconedDescreteDerivativeCheckBox.Value   = signal_typ.DD2 ; 
            app.signaltypeFastFourierTransformCheckBox.Value        = signal_typ.fft ;
            app.signaltypeDerivativeChannelsCheckBox.Value          = signal_typ.diffch ;
        end
        
        function signal_typ = clearsignaltype(app)
            app.signaltypetimenormalCheckBox.Enable                  = true ;
            app.siganltypeDescreteDerivativeCheckBox.Enable          = true ;
            app.signaltypeSeconedDescreteDerivativeCheckBox.Enable   = true ; 
            app.signaltypeFastFourierTransformCheckBox.Enable        = true ;
            app.signaltypeDerivativeChannelsCheckBox.Enable          = true ;
        
            app.signaltypetimenormalCheckBox.Value                  = true  ;
            app.siganltypeDescreteDerivativeCheckBox.Value          = false ;
            app.signaltypeSeconedDescreteDerivativeCheckBox.Value   = false ; 
            app.signaltypeFastFourierTransformCheckBox.Value        = false ;
            app.signaltypeDerivativeChannelsCheckBox.Value          = false ;
            signal_typ = struct('normal',app.signaltypetimenormalCheckBox.Value,'DD',app.siganltypeDescreteDerivativeCheckBox.Value ,...
                'DD2', app.signaltypeSeconedDescreteDerivativeCheckBox.Value,'fft',app.signaltypeFastFourierTransformCheckBox.Value,'diffch',app.signaltypeDerivativeChannelsCheckBox.Value);
        end
        
        function getandsetsignaltype(app,string)
                if isempty(app.currentfeature)
                    app.currentfeature = string;
                    app.lastfeature = [];
                    app.feature.(app.currentfeature).signaltype = clearsignaltype(app);
                else
                    %app.feature.(app.currentfeature).signaltype = getsignaltypes(app) ;
%                     app.lastfeature = app.currentfeature;
                    app.currentfeature =  string;
                    app.feature.(app.currentfeature).signaltype = updatesignaltypes(app) ;
                end
        end

        function peak = getpeaklist(app)
            selected = app.peakFunctionListBox.Value;
            normal = 0 ; fft = 0 ; DD=0; DD2=0; diffch=0;
            for i = 1:size(selected,2)
            switch selected{i}
                case 'Time series'
                    normal = 1 ;
                case 'FFT'
                    fft=1;
                case 'Descrete Derivative'
                    DD = 1 ;
                case 'Second Descrete Derivative'
                    DD2 = 1 ;
                case 'Derivative Channels'
                    diffch = 1 ;
            end
            end
            peak = struct('name','peak','normal',normal,'fft',fft,'dd',DD,'dd2',DD2,'diffch',diffch,'signaltype',[],'active',false);
        end
        
        function changefontcolor(app,value,varargin)
            others = zeros(1,nargin-2);
            for i = 1 : nargin -2
                others(i) = varargin{i}.Value;
            end
            if value && any(others) || ~value
               for i = 1 : nargin -2  
                 varargin{i}.FontColor = [0.00,0.00,0.00] ;
               end 
            elseif value && ~any(others)
                for i = 1 : nargin-2 
                    varargin{i}.FontColor = [1.00,0.00,0.00] ;
               end 
            end
        end 
        
        function  setreport(app,value,varargin)
            if value
                if ~isempty(app.currentfeature)
                    tempfeature = app.feature.(app.currentfeature);
                    fnames = fieldnames(tempfeature); 
                    text = sprintf('Feature: %s condition: ',tempfeature.name);
                    for i = 2 : size(fnames)-2
                        if app.feature.(app.currentfeature).(fnames{i})
                        text = [text , sprintf(' %s ',fnames{i})] ;
                        end
                    end
                    signals = fieldnames(app.feature.(app.currentfeature).signaltype);
                    text = [text , sprintf(' signaltype: ')];
                    for i = 1 : size(signals)
                        if app.feature.(app.currentfeature).signaltype.(signals{i})
                           text = [ text , sprintf(' %s ',signals{i})];
                        end
                    end
                    text = [text, sprintf(' was selected.')];
                    app.descriptiontext = app.TextArea.Value;
                    app.descriptiontext{end+1} =text;
                    app.TextArea.Value = app.descriptiontext;
                end
            else

                if ~isempty(app.currentfeature)
                    tempfeature = app.feature.(app.currentfeature);
                    fnames = fieldnames(tempfeature); 
                    text = sprintf('Feature: %s condition: ',tempfeature.name);
                    
                    if nargin == 3
                    text = [text , sprintf(' %s ',varargin{1})] ;
                    end

                    text = [text, sprintf(' was deselected.')];
                    app.descriptiontext = app.TextArea.Value;
                    app.descriptiontext{end+1} =text;
                    app.TextArea.Value = app.descriptiontext;
                end
                
            end

            
        end
        
        function result = checkchannels(app,diffch)
            if ~isa(diffch,'double')
                app.diffcheditfield.Tooltip = 'Please enter a valid two column vector (nx2) of Channels.';
                result = false;
            else
                [row, col] = size(diffch);
                if col == 2 && row > 0
                    app.diffcheditfield.BackgroundColor = [0.78,0.97,0.72];
                    result = true;
                    app.diffcheditfield.Tooltip = 'IT looks legitimate :)'; 
                else
                    app.diffcheditfield.Tooltip = 'Sample: [1 12; 2,4; 3,6] ';
                    result = false;
                end
            end 
        end
        
        function updatefeaturelistbox(app)
            fnames = fieldnames(app.feature);
            list = {}; indx = 0;
            for i = 1 : size(fnames)
                if app.feature.(fnames{i}).active
                    indx = indx + 1;
                    list(indx) = {fnames{i}};
                end
            end
            app.FeaturesListBox.Items = list;
           
        end

        function result = checkready(app)
            
            result(1) = checklamsp(app);
            selectedButton = app.WhattoDoButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Only Extract Feature'
                    if  app.eegnoofFiles ~= app.eegProperties.nooffiles
                        app.errortext = sprintf(' Number of egfiles in the folder does not match. The first %d files will be chosen.',app.eegProperties.nooffiles);
                    end
                case 'Classify Available Features'
                   
                case 'Extract & Classifiy '
                    
                case 'Process Available Results'
                    
                case 'All Together'        
            end
            
            if app.CSPCommonSpatialPatternCheckBox.Value && isempty(app.cspchannelsEditField.Value)
                app.processingProperties.featureproperties.cspclasses = app.eegProperties.channels ;    
            end
            
            if isempty(app.processingProperties.modeltype)
                modeltypefcn(app);
                app.processingProperties.modeltype = 'normal';
            end
            if isempty(app.processingProperties.kfold)
                app.processingProperties.kfold = app.KfoldCrossvalidationEditField.Value;
            end
            
            
            try
                app.processingProperties.classificationtype = updateclassifiers(app);             
                [app.processingProperties.featureextract, app.processingProperties.featuretoutelize] = updatefeature(app);
                result(2) = true;
            catch
                result(2) = false;
                text = 'An unexpected Error has Occured.';
                addreport(app,text);
            end
            result = all(result,'all');
        end
        
        function sgnltyp = getfeaturesignals(app,featurename)
                sgnltyp = [];
                indx = 0 ;
                sglnames = fieldnames(app.feature.(featurename).signaltype);
                for sgnl = 1 : size(sglnames,1)
                    if app.feature.(featurename).signaltype.(sglnames{sgnl})
                        indx = indx + 1;
                        sgnltyp{indx} = sglnames{sgnl};
                    end
                end
        end

        
        function deactiveunnecessary(app,param)
            switch param
                case 'ex'
                    paramt = true ;
                    paramf = true ;
                    paramc = false;
                    paramp = false;
                    colorlamp = [1 , 0 , 0.];
                    tooltiptext = '';
                case 'class'
                    paramt = false ;
                    paramf = false ;
                    paramc = true;
                    paramp = false;
                    colorlamp = [0.65 , 0.65 , 0.65];
                    tooltiptext = '';
                case 'ex&class'
                    paramt = true ;
                    paramf = true ;
                    paramc = true;
                    paramp = false;
                    colorlamp = [1 , 0 , 0.];
                    tooltiptext = '';
                case 'process'
                    paramt = false ;
                    paramf = false ;
                    paramc = false;
                    paramp = true;
                    colorlamp = [0.65 , 0.65 , 0.65];
                   tooltiptext = 'Choose those that are available in your file!';
                case 'all'
                    paramt = true ;
                    paramf = true ;
                    paramc = true ;
                    paramp = true ;
                    colorlamp = [1 , 0 , 0.];
                    tooltiptext = '';
                    
            end
            %top
            app.AvailableSamplesDropDown.Enable = paramt;
            app.ButterworthCheckBox.Enable = paramt;
            app.MSPCACheckBox.Enable = paramt ;
            %features
            app.FractalDimenssionHiguchiCheckBox.Enable = paramf;
            app.CSPCommonSpatialPatternCheckBox.Enable = paramf;
            app.PSDEstimationCheckBox.Enable = paramf;
            app.EntropyEstimationCheckBox.Enable = paramf ;
            app.HurstExponentCheckBox.Enable = paramf;
            app.LiapanuvExpCheckBox.Enable = paramf ;
            app.PeakCheckBox.Enable = paramf ;
            app.ProcessingParametersButton.Enable = paramf ;
            app.ProcessingparamLamp.Color = colorlamp;
            app.ReadyButton.Enable = paramf;
            app.turnonSwitch.Enable = ~paramf ;
            %class
            app.KfoldCrossvalidationEditField.Enable = paramc ;
            app.LDACheckBox.Enable = paramc;
            app.KNNCheckBox.Enable = paramc;
            app.MahalCheckBox.Enable = paramc;
            app.SVMCheckBox.Enable = paramc ;
            
            %process
            app.PlotFeaturesCheckBox.Enable = paramp ;
            app.PlotResultsCheckBox.Enable = paramp ;
            app.FeaturesListBox.Enable = paramp;
            app.ResultsListBox.Enable = paramp;
            
            app.FeaturesListBox.Tooltip = tooltiptext;
             
        end
        
        function result = checklamsp(app)
            result = true;
            if app.DirLamp.Color==[1,0,0]
                result = false;
                text = 'You have not Chosen any file/folder.';
                addreport(app,text);
            end
            if app.saveDirLamp.Color==[1,0,0]
                result = false;
                text = 'You have not Chosen any path for saving the results.';
                addreport(app,text);
            end
            if app.ProcessingparamLamp.Color==[1,0,0]
                result = false;
                text = 'You have entered processing parameters.';
                addreport(app,text);
            end   
            
        end
        
        function result = checkreadylamp(app)
            result = true;
                if app.DirLamp.Color == [1,0,0]
                    result = false;
                end
                if app.saveDirLamp.Color == [1,0,0]
                    result = false;
                end
                if app.ProcessingparamLamp.Color == [1,0,0]
                    result = false;
                end
            
        end
        
        function clsfierlist = updateclassifiers(app)
          
                 app.classifierlist = struct('lda',app.LDACheckBox.Value,...
                                         'mahal',app.MahalCheckBox.Value,...
                                         'knn',app.KNNCheckBox.Value,...
                                         'svm',app.SVMCheckBox.Value,...
                                         'dcnn',app.DCNNCheckBox.Value,...
                                         'lstmdnn',app.LSTMDNNCheckBox.Value,...
                                         'svmkernel',app.KernelEditField.Value,...
                                         'knnneighbour',app.knnNumberEditField.Value);
                clsfierlist = [];indx = 0;
                classifnames = fieldnames(app.classifierlist);
                for clf = 1 : size(classifnames,1) -2
                    if app.classifierlist.(classifnames{clf})
                        indx = indx +  1 ;
                        clsfierlist{indx} = classifnames{clf};
                    end
                end
            
        end
        
        function [ftrextract,ftrutelize] = updatefeature(app)
            
                ftrextract = []; ftrutelize = []; indx1 = 0; indx2 = 0;
                featurenames = fieldnames(app.feature);
                for ftr = 1 : size(featurenames,1)
                   
                    if app.feature.(featurenames{ftr}).active
                        indx1 = indx1 + 1;
                         ftrextract{indx1} = featurenames{ftr};
                        switch featurenames{ftr}
                            case 'higuchi'
                                sgnltyp = getfeaturesignals(app,featurenames{ftr});
    
                                if app.feature.higuchi.singlech
                                    indx2 = indx2 + 1 ;
                                  ftrutelize{indx2,1} = 'higuchisinglechannel' ; 
                                  ftrutelize{indx2,2} = sgnltyp ; 
                                end
                                if app.feature.higuchi.allch
                                    indx2 = indx2 + 1 ;
                                    ftrutelize{indx2,1} = 'higuchiallchannel';
                                    ftrutelize{indx2,2} = sgnltyp ;
                                end
                                if app.feature.higuchi.higcsp
                                    indx2 = indx2 + 1 ;
                                    ftrutelize{indx2,1} = 'csphiguchi';
                                    ftrutelize{indx2,2} = sgnltyp ;
                                end
                                
                            case 'csp'
                                sgnltyp = getfeaturesignals(app,featurenames{ftr});
                                indx2 = indx2 + 1 ;
                                ftrutelize{indx2,1} = 'cspnormal' ;
                                ftrutelize{indx2,2} = sgnltyp ;


                            case 'psd'
                                sgnltyp = getfeaturesignals(app,featurenames{ftr});
                                if app.feature.psd.normal
                                    indx2 = indx2 + 1 ;
                                    ftrutelize{indx2,1} = 'psdnormal' ;
                                    ftrutelize{indx2,2} =  sgnltyp;
                                end
                                if app.feature.psd.top
                                    indx2 = indx2 + 1 ;
                                    ftrutelize{indx2,1} = 'psdtoppeak';
                                    ftrutelize{indx2,2} = sgnltyp ;
                                end
                                if app.feature.psd.top3
                                    indx2 = indx2 + 1 ;
                                     ftrutelize{indx2,1} = 'psd3peaks';
                                     ftrutelize{indx2,2} =  sgnltyp ;
                                end
                                
                            case 'entropy'
                                sgnltyp = getfeaturesignals(app,featurenames{ftr});
                                if app.feature.entropy.singlech
                                    indx2 = indx2 + 1 ;
                                    ftrutelize{indx2,1} = 'entropysinglechannel' ;
                                    ftrutelize{indx2,2} =  sgnltyp;
                                end
                                if app.feature.entropy.allch
                                    indx2 = indx2 + 1 ;
                                    ftrutelize{indx2,1} = 'entropyallchannels' ;
                                    ftrutelize{indx2,2} =  sgnltyp ;
                                end
                                
                                app.processingProperties.featureproperties.mfactor = app.entropymfactor.Value ;
                                app.processingProperties.featureproperties.rfactor = app.entropyrfactor.Value ;
                                
                            case 'hurst'
                                sgnltyp = getfeaturesignals(app,featurenames{ftr});
                                if app.feature.hurst.singlech
                                    indx2 = indx2 + 1 ;
                                    ftrutelize{indx2,1} = 'hurstexpsinglechannel';
                                    ftrutelize{indx2,2} =  sgnltyp;
                                end
                                if app.feature.hurst.allch
                                    indx2 = indx2 + 1 ;
                                    ftrutelize{indx2,1} = 'hurstexpallchannels'; 
                                    ftrutelize{indx2,2} = sgnltyp;
                                end
                                
                            case 'liap'
                                sgnltyp = getfeaturesignals(app,featurenames{ftr});
                                if app.feature.liap.singlech
                                    indx2 = indx2 + 1 ;
                                    ftrutelize{indx2,1} = 'liapanuvexpsinglechannel' ;
                                    ftrutelize{indx2,2} =  sgnltyp;
                                end
                                if app.feature.liap.allch
                                    indx2 = indx2 + 1 ;
                                    ftrutelize{indx2,1} = 'liapanuvexpallchannels' ;
                                    ftrutelize{indx2,2} = sgnltyp;
                                end
                                
                            case 'peak'
                                if app.feature.peak.normal
                                    sgnltyp = [];
                                    sgnltyp = {'normal'};
                                    if app.feature.peak.dd
                                        sgnltyp = {sgnltyp , 'DD'};
                                    end
                                    if  app.feature.peak.dd2
                                        sgnltyp = {sgnltyp , 'DD2'};
                                    end
                                    if app.feature.peak.diffch
                                        sgnltyp = {sgnltyp , 'diffch'};
                                    end
                                    if app.feature.peak.fft
                                        sgnltyp = {sgnltyp , 'fft'};
                                    end
                                end
                                indx2 = indx2 + 1 ;
                                ftrutelize{indx,1} = 'peak' ;  
                                ftrutelize{indx,2} = sgnltyp ;  
                        end
                    end
                end
            
        end
    end
    
    methods (Access = public)
        
        function getinfopageapp(app,eegPropertiesinfo)
            app.eegProperties = eegPropertiesinfo ; 
            if checkreadylamp(app)
                app.ReadyLamp.Color = [1,1,0];
            end
        end
        
        function getprocessingparam(app,eegPropertiespp,processingPropertiespp)
            app.eegProperties = eegPropertiespp;
            app.processingProperties = processingPropertiespp;
            app.ProcessingparamLamp.Color = [0,1,0.0];
            if checkreadylamp(app)
                app.ReadyLamp.Color = [1,1,0];
            end
        end
        
        function addreport(app,text)
            
                app.descriptiontext = app.TextArea.Value;
                app.descriptiontext{end+1} =text;
                app.TextArea.Value = app.descriptiontext;
            
        end
        
        
        function chengelamp(app,Lname,colorname)
            switch Lname
                case 'load'
                    app.FileLoadingLamp.color = app.color.(colorname);
                case 'preprocess'
                    app.PreprocessingLamp.color = app.color.(colorname);
                case 'featureextraction'
                   app.FeatureExtractionLamp.color =  app.color.(colorname);
                case 'classificaion'
                     app.ClassificationLamp.color = app.color.(colorname);
                case 'plot'
                     app.PlotLamp.color = app.color.(colorname);
                case 'save'
                     app.SavingResultsLamp.color = app.color.(colorname);  
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function statupfcn(app)
            firstbuild(app);
            deactiveunnecessary(app,'ex');
        end

        % Selection changed function: WhattoDoButtonGroup
        function whattodofcn(app, event)
            selectedButton = app.WhattoDoButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Only Extract Feature'
                    app.processingProperties.FEtype = 'extract';
                    app.Choosefile.Tooltip = 'Please Choose the folder containing EEGs.';
                    app.inputfilesLabel.Text =  'Input Folder:';
                    deactiveunnecessary(app,'ex'); 
                    
                case 'Classify Available Features'
                    app.processingProperties.FEtype = {'load','classify'};
                    app.Choosefile.Tooltip = 'Please Choose the Feature file.';
                    app.AvailableSamplesDropDown.Enable = false;
                    app.inputfilesLabel.Text =  'Input File:';
                    deactiveunnecessary(app,'class'); 
                    
                    
                case 'Extract & Classifiy '
                    app.processingProperties.FEtype = {'extract','classify'};
                    app.Choosefile.Tooltip = 'Please Choose the folder containing EEGs.';
                     app.inputfilesLabel.Text =  'Input Folder:';
                     deactiveunnecessary(app,'ex&class'); 
                     
                case 'Process Available Results'
                    app.processingProperties.FEtype = 'process';
                    app.AvailableSamplesDropDown.Enable = false;
                    app.Choosefile.Tooltip = 'Please Choose the BCI Result file.';
                    app.inputfilesLabel.Text =  'Input File:';
                     
                    deactiveunnecessary(app,'process'); 
                     
                     
                case 'All Together'
                     app.processingProperties.FEtype = {'extract','classify','process'};
                     app.Choosefile.Tooltip = 'Please Choose the folder containing EEGs.';
                      app.inputfilesLabel.Text =  'Input Folder:';
                      deactiveunnecessary(app,'all'); 
            end
            
        end

        % Value changed function: AvailableSamplesDropDown
        function sampleeegfcn(app, event)
            value = app.AvailableSamplesDropDown.Value;
            app.eegProperties.sampleeeg = value ;
            if ~strcmp(value,'none')
                app.eegProperties.channels = 1:16;      
                app.eegProperties.inputmethod = 'onefile';  
                app.eegProperties.noofclass = 5;
                app.eegProperties.versus = 5 ;
                app.eegProperties.nooffiles = 3;
                app.eegProperties.nooftrials = 45;
                app.eegProperties.classrow = 23;
                app.eegProperties.imaginerow = 19;
                app.eegProperties.trialrow = 20;
                app.eegProperties.eegrows = 2:17;
                app.eegProperties.controlclass = 5;
                app.eegProperties.registererrorrow = 22;
                app.eegProperties.pauserow = 21;
                app.eegProperties.trialmarker = 'trialno';
                app.eegProperties.imaginemarker = 'one';
                app.eegProperties.samplingrate = 256 ;
                
                app.DirLamp.Color = [0,1,0];
                app.InfoButton.Enable = false;
                app.Choosefile.Enable = false;
                app.flaginfo = false;
            end
        end

        % Value changed function: turnonSwitch
        function turnonfcn(app, event)
            value = app.turnonSwitch.Value;
            if strcmp(value,'On')
                try
                app.StatusLamp.Color = [0,1,0];
                selectedButton = app.WhattoDoButtonGroup.SelectedObject;
                text = 'Process now begins...';
                addreport(app,text);
                
                switch selectedButton.Text
                    case 'Only Extract Feature'
                        [app.bciresults] = BCIprocessor(app,app.processingProperties,app.eegProperties);
                    case 'Classify Available Features'
                       [app.bciresults] = BCIprocessor(app,app.processingProperties,app.eegProperties);
                    case 'Extract & Classifiy '
                        [app.bciresults] = BCIprocessor(app,app.processingProperties,app.eegProperties);
                    case 'Process Available Results'
                        [app.bciresults] = BCIprocessor(app,'process',app.processingProperties);
                    case 'All Together'  
                        [app.bciresults] = BCIprocessor(app,app.processingProperties,app.eegProperties);
                end
                catch
                  app.turnonSwitch.Value = 'Off';
                  text = 'Sonething went wrong';
                  addreport(app,text);
                  app.StatusLamp.Color = app.color.yellow ;
                end
            else
                
            end
        
        end

        % Button pushed function: Choosefile
        function choosedirfcn(app, event)
            try
                currentfolder = pwd;
                text = sprintf('Please wait till the file(s) are loaded...');
                addreport(app,text);
                selectedButton = app.WhattoDoButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case {'Only Extract Feature','Extract & Classifiy ', 'All Together' }
                        app.processingProperties.eegfolderpathstr = uigetdir(currentfolder,'Choose EEG Folder:'); 
                         if ~ischar(app.processingProperties.eegfolderpathstr)
                            f = errordlg('You did not select a file.', 'File Loading Error');
                            app.flaginfo = ture;
                            app.DirLamp.Color = [1.00,0,0.00];
                         else
                              filesinside = what(app.processingProperties.eegfolderpathstr);
                              nooffiles = size(filesinside.mat,1);
                              text = sprintf('%d Mat file(s) have been found.',nooffiles);
                              addreport(app,text);
                              text = sprintf('Please add the info.');
                              addreport(app,text);
                              app.InfoButton.Enable = true;
                              app.DirLamp.Color = [0.00,1.00,0.00];
                         end
                         
                    case 'Classify Available Features'
                        [app.processingProperties.filename, app.processingProperties.featurepathstr] = uigetfile('*.mat', 'Select the Feature file .mat');
                        if ~ischar(app.eegProperties.filename)
                          f = errordlg('You did not select a file.', 'File Loading Error');
                          app.flaginfo = ture;
                          app.DirLamp.Color = [1.00,0,0.00];
                        else
                            filename = fullfile(app.processingProperties.featurepathstr, app.processingProperties.filename);
                            filestruct = matfile(filename);
                            details = whos(filestruct);
                            filestruct = load(filename);
                            app.file = filestruct.(details.name);
                            if ~isa(app.file,'bciresults')
                                errordlg('A bcifeature should be loaded.');
                            else
                                text = sprintf('A file has been Chosen.\n');
                                app.DirLamp.Color = [0.00,1.00,0.00];
                                app.addreport(app,text);
                            end
                        end
       
                    case 'Process Available Results'
                        [app.processingProperties.filename, app.processingProperties.resultpathstr] = uigetfile('*.mat', 'Select the Result file .mat');
                        if ~ischar(app.eegProperties.filename)
                          f = errordlg('You did not select a file.', 'File Loading Error');
                          app.flaginfo = ture;
                          app.DirLamp.Color = [1.00,0,0.00];
                          else
                            filename = fullfile(app.processingProperties.resultpathstr, app.processingProperties.filename);
                            filestruct = matfile(filename);
                            details = whos(filestruct);
                            filestruct = load(filename);
                            app.file = filestruct.(details.name);
                            if ~isa(app.file,'bciresults')
                                errordlg('A bcifeature should be loaded.');
                            else
                                text = sprintf('A file has been Chosen.\n');
                                app.DirLamp.Color = [0.00,1.00,0.00];
                                app.addreport(app,text);
                            end
                        end
                end

                if processProperties.pathstr
                    app.DirLamp.Color = [0.00,1.00,0.00];
                    text = sprintf('Something went wrong Please try again.\n');
                    addreport(app,text);
                end
            catch E
                
            end
        end

        % Value changed function: butterhighb
        function highboundfcn(app, event)
            value = app.butterhighb.Value;
            if ~ismepty(value)
                app.eegProperties.filterproperties.upperboundery = value;
            else
                app.butterhighb.BackgroundColor = [0.98, 0.53, 0.53];
            end
            
        end

        % Value changed function: butterlowb
        function lowbfcn(app, event)
            value = app.butterlowb.Value;
            if ~isempty(value)
                app.eegProperties.filterproperties.lowerboundery = value;
            else
                app.butterlowb.BackgroundColor = [0.98, 0.53, 0.53];
            end
        end

        % Value changed function: MSPCACheckBox
        function wmspcafcn(app, event)
            value = app.MSPCACheckBox.Value;
            if value
                app.eegProperties.filtertype = 'wmspca';
                app.ButterworthCheckBox.Value = false;
                app.butterlowb.Enable = false;
                app.butterhighb.Enable = false;
                app.butterlevel.Enable = false;
                app.LevelEditField.Enable = true;
                app.LevelEditField.Editable = true;
                app.mspcafunction.Enable = true;
            else
                app.LevelEditField.Enable = false;
                app.LevelEditField.Editable = false;
                app.mspcafunction.Enable = false;
                app.eegProperties.filtertype = 'none';   
            end
        end

        % Value changed function: ButterworthCheckBox
        function butterfcn(app, event)
            value = app.ButterworthCheckBox.Value;
            if value
                app.eegProperties.filtertype = 'butterworth';
                app.MSPCACheckBox.Value = false ;
                app.mspcafunction.Enable = false;
                app.LevelEditField.Enable = false;
                app.LevelEditField.Editable = false;
                app.butterlowb.Enable = true;
                app.butterhighb.Enable = true;
                app.butterlevel.Enable = true;
            else
                app.butterlowb.Enable = false;
                app.butterhighb.Enable = false;
                app.butterlevel.Enable = false;
                app.eegProperties.filtertype = 'none';   
            end
        end

        % Value changed function: KfoldCrossvalidationEditField
        function kfoldfcn(app, event)
            value = app.KfoldCrossvalidationEditField.Value;
            app.processingProperties.kfold = value;
        end

        % Value changed function: FractalDimenssionHiguchiCheckBox
        function higuchifeaturefcn(app, event)
            value = app.FractalDimenssionHiguchiCheckBox.Value;    
            
            if value
                app.higuchiSingleChannelCheckBox.Enable = true;
                app.higuchiAllChannelsCheckBox.Enable = true;
                app.higuchiCSPHiguchiCheckBox.Enable = true;
                app.signaltypetimenormalCheckBox.Value = true;
                
                app.currentfeature = 'higuchi';
                getandsetsignaltype(app,'higuchi');
                app.higuchiSingleChannelCheckBox.Value = true;
                app.feature.(app.currentfeature).singlech = true;
                app.feature.higuchi.active = true;
                app.signaltype.Title = 'Signal Type for: Higuchi Fractal Dimension';
                
                
            else
                app.higuchiSingleChannelCheckBox.Enable = false;
                app.higuchiAllChannelsCheckBox.Enable = false;
                app.higuchiCSPHiguchiCheckBox.Enable = false;
                app.feature.higuchi.active = false;
                app.currentfeature = app.lastfeature ;
                app.lastfeature = 'higuchi';
            end
            changefontcolor(app,value,app.higuchiSingleChannelCheckBox,app.higuchiAllChannelsCheckBox,app.higuchiCSPHiguchiCheckBox);
            setreport(app,value,'all modes');
            updatefeaturelistbox(app);
        end

        % Value changed function: CSPCommonSpatialPatternCheckBox
        function cspfeaturefcn(app, event)
            value = app.CSPCommonSpatialPatternCheckBox.Value;         
            
            if value
                app.cspchannelsEditField.Enable = true;
                app.cspchannelsEditField.Editable = true;
                app.CSPvarparam.Enable = true;
                app.CSPvarparam.Editable = true;


                app.currentfeature = 'csp';
                app.signaltypetimenormalCheckBox.Value = true;
                app.feature.csp.active = true;
                getandsetsignaltype(app,'csp');
                app.signaltype.Title = 'Signal Type for: Common Spatial Pattern';
                app.cspchannelsEditField.Tooltip = 'Please enter new Channel list if you want different eeg channels to be used.';
            
            else
                app.cspchannelsEditField.Enable = false;
                app.CSPvarparam.Enable = false;
                app.feature.csp.active = false;
                app.currentfeature = app.lastfeature ;
                app.lastfeature = 'csp';
                
            end
            setreport(app,value,'all modes');
            updatefeaturelistbox(app);
        end

        % Value changed function: PSDEstimationCheckBox
        function psdfeature(app, event)
            value = app.PSDEstimationCheckBox.Value;

            if value
                app.psdCompleteCheckBox.Enable = true;
                app.psdTop3PeaksCheckBox.Enable = true;
                app.psdTopPeakCheckBox.Enable = true ;
                app.currentfeature = 'psd';
                app.signaltypetimenormalCheckBox.Value = true;
                getandsetsignaltype(app,'psd');
                app.signaltype.Title = 'Signal Type for: Power Spectral Density Estimation';
                app.psdCompleteCheckBox.Value = true;
                app.feature.(app.currentfeature).normal = true;
                app.feature.psd.active = true;
            else
                app.psdCompleteCheckBox.Enable = false;
                app.psdTop3PeaksCheckBox.Enable = false;
                app.psdTopPeakCheckBox.Enable = false ;
                app.feature.psd.active = false;
                app.currentfeature = app.lastfeature ;
                app.lastfeature = 'psd';
            end
            setreport(app,value,'all modes');
            changefontcolor(app,value,app.psdCompleteCheckBox,app.psdTopPeakCheckBox,app.psdTop3PeaksCheckBox);
            updatefeaturelistbox(app);
        end

        % Value changed function: EntropyEstimationCheckBox
        function entropyfeature(app, event)
            value = app.EntropyEstimationCheckBox.Value;
                   
            if value
                app.entropySingleChannelCheckBox.Enable = true;
                app.entropyAllChannelsCheckBox.Enable = true;
                app.entropymfactor.Enable = true;
                app.entropyrfactor.Enable = true;
                app.currentfeature = 'entropy';
                app.signaltypetimenormalCheckBox.Value = true;
                getandsetsignaltype(app,'entropy');
                app.signaltype.Title = 'Signal Type for: Entropy Estimation';
                app.entropySingleChannelCheckBox.Value = true ;
                app.feature.(app.currentfeature).singlech = true;
                app.feature.entropy.active = true;
            else
                app.entropySingleChannelCheckBox.Enable = false;
                app.entropyAllChannelsCheckBox.Enable = false;
                app.entropymfactor.Enable = false;
                app.entropyrfactor.Enable = false;
                app.currentfeature = app.lastfeature ;
                app.lastfeature = 'entropy';
                app.feature.entropy.active = false;
            end
            setreport(app,value,'all modes');
            changefontcolor(app,value,app.entropySingleChannelCheckBox,app.entropyAllChannelsCheckBox,app.entropymfactor,app.entropyrfactor);
            updatefeaturelistbox(app);
        end

        % Value changed function: HurstExponentCheckBox
        function hurstfeaturefcn(app, event)
            value = app.HurstExponentCheckBox.Value;
     
            if value
                app.hurstAllChannelsCheckBox.Enable = true;
                app.hurstSingleChannelCheckBox.Enable = true;
                app.signaltypetimenormalCheckBox.Value = true;
                app.currentfeature = 'hurst';
                getandsetsignaltype(app,'hurst');
                app.signaltype.Title = 'Signal Type for: Hurst Epx.';
                app.hurstSingleChannelCheckBox.Value = true;
                app.feature.(app.currentfeature).singlech = true;
                app.feature.hurst.active = true;
            else
                app.hurstAllChannelsCheckBox.Enable = false;
                app.hurstSingleChannelCheckBox.Enable = false;
                app.feature.hurst.active = false;
                app.currentfeature = app.lastfeature ;
                app.lastfeature = 'hurst';
            end
            setreport(app,value,'all modes');
            changefontcolor(app,value,app.hurstSingleChannelCheckBox,app.hurstAllChannelsCheckBox);
            updatefeaturelistbox(app);
        end

        % Value changed function: LiapanuvExpCheckBox
        function liapfeaturefcn(app, event)
            value = app.LiapanuvExpCheckBox.Value;
                   
            if value
                app.liapSingleChannelCheckBox.Enable = true;
                app.liapAllChannelsCheckBox.Enable = true;
                app.signaltypetimenormalCheckBox.Value = true;app.signaltypetimenormalCheckBox.Value = true;
                app.currentfeature = 'liap';
                getandsetsignaltype(app,'liap');
                app.signaltype.Title = 'Signal Type for: Liapanuv Exp.';
                app.liapSingleChannelCheckBox.Value = true;
                app.feature.(app.currentfeature).singlech = true;
                app.feature.liap.active = true;
                
            else
                app.liapSingleChannelCheckBox.Enable = false;
                app.liapAllChannelsCheckBox.Enable = false;

                app.feature.liap.active = false;
                app.currentfeature = app.lastfeature ;
                app.lastfeature = 'liap';
            end
            setreport(app,value,'all modes');
            changefontcolor(app,value,app.liapSingleChannelCheckBox,app.liapAllChannelsCheckBox);
            updatefeaturelistbox(app);
        end

        % Value changed function: PeakCheckBox
        function peakfeaturefcn(app, event)
            value = app.PeakCheckBox.Value;
            
            if value
                app.peakFunctionListBox.Enable = true;
                getandsetsignaltype(app,'peak');
                app.signaltype.Title = 'Signal Type for: Peak';
                app.feature.peak.active = true;
            else
                app.peakFunctionListBox.Enable = false;
                app.feature.peak.active = false;
                app.currentfeature = app.lastfeature ;
                app.lastfeature = 'peak';
                
            end
            setreport(app,value,'all modes');
            updatefeaturelistbox(app);
        end

        % Button pushed function: savefile_2
        function savefilefcn(app, event)
            try
            currentfolder = pwd;
            app.processingProperties.savepathstr = uigetdir(currentfolder,'Save Features/Results in:');  
            if ischar(app.processingProperties.savepathstr)
                app.saveDirLamp.Color = [0,1,0];
                text = sprintf(' Path : %s has been chosen.\n',app.processingProperties.savepathstr);
                addreport(app,text);
                if checkreadylamp(app)
                    app.ReadyLamp.Color = [1,1,0];
                end
            else
                app.processingProperties.savepathstr = currentfolder;
                text = sprintf(' Something Went wrong. The current folder has been chosen.\n');
                addreport(app,text)
            end
            catch
                app.processingProperties.savepathstr = currentfolder;
                text = sprintf(' Something Went wrong. The current folder has been chosen.\n');
                addreport(app,text);
                
            end
            
        end

        % Value changed function: higuchiSingleChannelCheckBox
        function higsinglechfcn(app, event)
            value = app.higuchiSingleChannelCheckBox.Value;
            app.feature.higuchi.singlech = value;
            
            if value
                app.feature.higuchi.active = true;
                app.currentfeature = 'higuchi';
                getandsetsignaltype(app,'higuchi');
                app.signaltype.Title = 'Signal Type for: Higuchi Fractal Dimension (Higuchi)';
            end
            setreport(app,value,'singlechannel');
            changefontcolor(app,1,app.higuchiSingleChannelCheckBox,app.higuchiAllChannelsCheckBox,app.higuchiCSPHiguchiCheckBox);
        end

        % Value changed function: higuchiAllChannelsCheckBox
        function higallchfcn(app, event)
            value = app.higuchiAllChannelsCheckBox.Value;
            app.feature.higuchi.allch = value;
            
            if value
                app.feature.higuchi.active = true;
                app.currentfeature = 'higuchi';
                getandsetsignaltype(app,'higuchi');
                app.signaltype.Title = 'Signal Type for: Higuchi Fractal Dimension (Higuchi)';
            end
            changefontcolor(app,1,app.higuchiSingleChannelCheckBox,app.higuchiAllChannelsCheckBox,app.higuchiCSPHiguchiCheckBox);
            setreport(app,value,'allchannel');
        end

        % Value changed function: higuchiCSPHiguchiCheckBox
        function higcspfcn(app, event)
            value = app.higuchiCSPHiguchiCheckBox.Value;
            app.feature.higuchi.higcsp = value;
            
            if value
                app.feature.higuchi.active = true;
                app.currentfeature = 'higuchi';
                getandsetsignaltype(app,'higuchi');
                app.signaltype.Title = 'Signal Type for: Higuchi Fractal Dimension (Higuchi)';
            end
            changefontcolor(app,1,app.higuchiSingleChannelCheckBox,app.higuchiAllChannelsCheckBox,app.higuchiCSPHiguchiCheckBox);
            setreport(app,value,'allchannel');
        end

        % Value changed function: CSPvarparam
        function cspvarfcn(app, event)
            value = app.CSPvarparam.Value;
            app.feature.csp.var = value; 
            app.currentfeature = 'csp';

        end

        % Value changed function: cspchannelsEditField
        function cspchannelsfcn(app, event)
            value = app.cspchannelsEditField.Value;
            app.feature.csp.channels = value;
            app.currentfeature = 'csp';
            if value
                app.feature.csp.active = true; 
            end
            csplist = str2num(value);
            if ~isa(csplist,'double')
                app.cspchannelsEditField.Tooltip = 'Please enter a valid row vector (1xn) of Channels.';
            else
                if length(size(value)) == 2 && size(value,1) == 1 
                    app.cspchannelsEditField.BackgroundColor = [0.78,0.97,0.72];
                else
                    app.cspchannelsEditField.Tooltip = 'Sample: [1,2,3] or 1:64';
                end
            end
        end

        % Value changed function: psdCompleteCheckBox
        function psdcompletefcn(app, event)
            value = app.psdCompleteCheckBox.Value;
            app.feature.psd.normal = value;
            app.currentfeature = 'psd';
            changefontcolor(app,1,app.psdCompleteCheckBox,app.psdTopPeakCheckBox,app.psdTop3PeaksCheckBox);
            setreport(app,value,'normal');
            if value
            app.feature.psd.active = true;
            
                getandsetsignaltype(app,'psd');
                app.signaltype.Title = 'Signal Type for: PSD';
            end
            
        end

        % Value changed function: entropySingleChannelCheckBox
        function entropysinglechfcn(app, event)
            value = app.entropySingleChannelCheckBox.Value;
            app.feature.entropy.singlech = value;         
            app.currentfeature = 'entropy';
            if value
            app.feature.entropy.active = true;
            app.entropymfactor.Enable = true;
            app.entropyrfactor.Enable = true;
           
                getandsetsignaltype(app,'entropy');
                app.signaltype.Title = 'Signal Type for: Entropy';
            
            end
            setreport(app,value,'singlechannel');
            changefontcolor(app,1,app.entropySingleChannelCheckBox,app.entropyAllChannelsCheckBox);
        end

        % Value changed function: entropyAllChannelsCheckBox
        function entropyallchfcn(app, event)
            value = app.entropyAllChannelsCheckBox.Value;
            app.feature.entropy.allch = value;
            changefontcolor(app,1,app.entropySingleChannelCheckBox,app.entropyAllChannelsCheckBox);
            app.currentfeature = 'entropy';
            if value
                app.feature.entropy.active = true;
                app.entropymfactor.Enable = true;
                app.entropyrfactor.Enable = true;
                getandsetsignaltype(app,'entropy');
                app.signaltype.Title = 'Signal Type for: Entropy';
            
            end
            setreport(app,value,'allchannel');
        end

        % Value changed function: entropymfactor
        function entropymfacfcn(app, event)
            value = app.entropymfactor.Value;
            app.feature.entropy.mfactor = value;
            app.currentfeature = 'entropy';
            getandsetsignaltype(app,'entropy');
            app.signaltype.Title = 'Signal Type for: Entropy';
            
        end

        % Value changed function: entropyrfactor
        function entropyrfacfcn(app, event)
            value = app.entropyrfactor.Value;
            app.feature.entropy.rfactor = value;
            app.currentfeature = 'entropy';
            getandsetsignaltype(app,'entropy');
            app.signaltype.Title = 'Signal Type for: Entropy';
            
           
        end

        % Value changed function: hurstSingleChannelCheckBox
        function hurstsinglechfcn(app, event)
            value = app.hurstSingleChannelCheckBox.Value;
            app.feature.hurst.singlech = value;
            app.feature.hurst.active = true;
            changefontcolor(app,1,app.hurstSingleChannelCheckBox,app.hurstAllChannelsCheckBox);
            app.currentfeature = 'hurst';
            if value
            app.feature.(app.currentfeature).singlech = true;
            getandsetsignaltype(app,'hurst');
                app.signaltype.Title = 'Signal Type for: Hurst Exp.';
            else
                app.feature.(app.currentfeature).singlech = false;
            end
            setreport(app,value,'singlechannel');
        end

        % Value changed function: hurstAllChannelsCheckBox
        function hurstallchfcn(app, event)
            value = app.hurstAllChannelsCheckBox.Value;
            app.feature.hurst.allch = value;
            app.feature.hurst.active = true;
            changefontcolor(app,1,app.hurstSingleChannelCheckBox,app.hurstAllChannelsCheckBox);
            app.currentfeature = 'hurst';
            if value
                app.feature.(app.currentfeature).allch = true;
                getandsetsignaltype(app,'hurst');
                app.signaltype.Title = 'Signal Type for: Hurst Exp.';
            else
                app.feature.(app.currentfeature).allch = false;
            end
            
            setreport(app,value,'allchannel');
        end

        % Value changed function: liapSingleChannelCheckBox
        function liapsinglechfcn(app, event)
            value = app.liapSingleChannelCheckBox.Value;
            app.feature.liap.singlech = value;
            app.feature.liap.active = true;
            changefontcolor(app,1,app.liapSingleChannelCheckBox,app.liapAllChannelsCheckBox);
            app.currentfeature = 'liap';
            if value
                app.feature.(app.currentfeature).singlech = true;
                getandsetsignaltype(app,'liap');
                app.signaltype.Title = 'Signal Type for: Liapanuv Exp.';
            else
                app.feature.(app.currentfeature).singlech = false;
            end
            setreport(app,value,'singlechannel');
        end

        % Value changed function: liapAllChannelsCheckBox
        function liapallchfcn(app, event)
            value = app.liapAllChannelsCheckBox.Value;
            app.feature.liap.allch = value;
            app.feature.liap.active = true;
            app.currentfeature = 'liap';
            if value
                app.feature.(app.currentfeature).allch = true;
                getandsetsignaltype(app,'liap');
                app.signaltype.Title = 'Signal Type for: Liapanuv Exp.';
            else
                app.feature.(app.currentfeature).allch = false;
            end
            
            changefontcolor(app,1,app.liapSingleChannelCheckBox,app.liapAllChannelsCheckBox);
            setreport(app,value,'allchannel');
        end

        % Value changed function: peakFunctionListBox
        function peaklistfcn(app, event)
            value = app.peakFunctionListBox.Value;
            peak = getpeaklist(app);
            app.currentfeature = 'peak';
            app.feature.peak = peak; 
            if value
            app.feature.peak.active = true;
            end
        end

        % Value changed function: psdTopPeakCheckBox
        function psdtopfcn(app, event)
            value = app.psdTopPeakCheckBox.Value;
            app.currentfeature = 'psd';
            if value
                app.feature.(app.currentfeature).top = true;
                getandsetsignaltype(app,'psd');
                app.signaltype.Title = 'Signal Type for: Power Spectral Density Estimation';
            else
                app.feature.(app.currentfeature).top = false;
            end
            changefontcolor(app,1,app.psdCompleteCheckBox,app.psdTopPeakCheckBox,app.psdTop3PeaksCheckBox);
            setreport(app,value,'toppeak');
        end

        % Value changed function: psdTop3PeaksCheckBox
        function psdtop3fcn(app, event)
            value = app.psdTop3PeaksCheckBox.Value;
            app.currentfeature = 'psd';
            if value
                app.feature.(app.currentfeature).top3 = true;
                getandsetsignaltype(app,'psd');
                app.signaltype.Title = 'Signal Type for: Power Spectral Density Estimation';
            else
               app.feature.(app.currentfeature).top3 = false; 
            end
            
            changefontcolor(app,1,app.psdCompleteCheckBox,app.psdTopPeakCheckBox,app.psdTop3PeaksCheckBox);
            setreport(app,value,'top3peaks');
        end

        % Value changed function: signaltypeFastFourierTransformCheckBox
        function fftfcn(app, event)
            value = app.signaltypeFastFourierTransformCheckBox.Value;
            app.feature.(app.currentfeature).signaltype = getsignaltypes(app) ;
            setreport(app,value);
        end

        % Value changed function: siganltypeDescreteDerivativeCheckBox
        function ddfcn(app, event)
            value = app.siganltypeDescreteDerivativeCheckBox.Value;
            app.feature.(app.currentfeature).signaltype = getsignaltypes(app) ;
            setreport(app,value);
        end

        % Value changed function: 
        % signaltypeSeconedDescreteDerivativeCheckBox
        function dd2fcn(app, event)
            value = app.signaltypeSeconedDescreteDerivativeCheckBox.Value;
            app.feature.(app.currentfeature).signaltype = getsignaltypes(app) ;
            setreport(app,value);
            
        end

        % Value changed function: signaltypeDerivativeChannelsCheckBox
        function diffchfcn(app, event)
            value = app.signaltypeDerivativeChannelsCheckBox.Value;
            if value
                app.diffcheditfield.Enable = true;
                app.diffcheditfield.Tooltip = 'Please enter a matrix of channels with two columns.';
                app.diffcheditfield.BackgroundColor = [0.98, 0.53, 0.53];
            else
                app.diffcheditfield.Enable = false;
            end
            
        end

        % Value changed function: diffcheditfield
        function diffchchannelsfcn(app, event)
            value = app.diffcheditfield.Value;
            if value
                diffch = str2num(value);
                if  checkchannels(app,diffch)
                    app.feature.(app.currentfeature).signaltype = getsignaltypes(app) ;
                    app.processingProperties.featureproperties.differentialchannels = diffch;
                    setreport(app,value);
                else
                    app.diffcheditfield.FontColor = [1, 0, 0 ];
                end
            end
        end

        % Value changed function: signaltypetimenormalCheckBox
        function normalfcn(app, event)
            value = app.signaltypetimenormalCheckBox.Value;
            app.feature.(app.currentfeature).signaltype = getsignaltypes(app) ;
            setreport(app,value);
        end

        % Button pushed function: InfoButton
        function fileinfofcn(app, event)
            app.infoApp = infobutton(app,app.eegProperties) ;
            
        end

        % Button pushed function: ProcessingParametersButton
        function processingparamfcn(app, event)
            app.processingparamApp = processingparam(app);
        end

        % Value changed function: LDACheckBox
        function ldcfcn(app, event)
            value = app.LDACheckBox.Value;
            app.classifierlist.lda = value ;
        end

        % Value changed function: MahalCheckBox
        function mahalfcn(app, event)
            value = app.MahalCheckBox.Value;
            app.classifierlist.mahal = value;
        end

        % Value changed function: KNNCheckBox
        function knnfcn(app, event)
            value = app.KNNCheckBox.Value;
            app.classifierlist.knn = value;
            if value
                app.knnNumberEditField.Enable = true;
                app.knnNumberEditField.Editable = true;
            else
                app.knnNumberEditField.Enable = false;
                app.knnNumberEditField.Editable = false;
            end
                
            
        end

        % Value changed function: SVMCheckBox
        function svmfcn(app, event)
            value = app.SVMCheckBox.Value;
            app.classifierlist.svm = value;
            if value
            app.KernelEditField.Editable = true;
            app.KernelEditField.Enable = true;
            else
                app.KernelEditField.Editable = false;
                app.KernelEditField.Enable = false;
            end
          
        end

        % Value changed function: DCNNCheckBox
        function dcnnfcn(app, event)
            value = app.DCNNCheckBox.Value;
            app.classifierlist.dcnn = value;
        end

        % Value changed function: LSTMDNNCheckBox
        function lstmdnn(app, event)
            value = app.LSTMDNNCheckBox.Value;
            app.classifierlist.lstmdnn = value;
        end

        % Value changed function: PlotFeaturesCheckBox
        function plotfeaturesfcn(app, event)
            value = app.PlotFeaturesCheckBox.Value;
            if value
                app.FeaturesListBox.Enable = true;
                
            else
                app.FeaturesListBox.Enable = false;
            end
        end

        % Value changed function: PlotResultsCheckBox
        function plotresultsfcn(app, event)
            value = app.PlotResultsCheckBox.Value;
            if value
                app.ResultsListBox.Enable = true;
            else
                app.ResultsListBox.Enable = false;  
            end
        end

        % Selection changed function: HowtoUseFeaturesButtonGroup
        function modeltypefcn(app, event)
            selectedButton = app.HowtoUseFeaturesButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Separately'
                    app.processingProperties.modeltype = 'normal';
                case 'All Together'
                    app.processingProperties.modeltype = 'allinone';
                case 'Last Best Results'
                    app.processingProperties.modeltype = 'allbests';
            end
            
        end

        % Value changed function: ReadyButton
        function readybuttfcn(app, event)
            value = app.ReadyButton.Value;
            if value
                    if checkready(app)
                        app.ReadyLamp.Color = [0,1,0];
                        app.StatusLamp.Color = [1,0.41,0.16];
                        app.turnonSwitch.Enable = true;
                    else
                        app.ReadyButton.Text = 'Check Again!';
                        app.ReadyLamp.Color = [1,0,0];
                        app.StatusLamp.Color = [1,0.00,0.00];
                    end
               
            else
                app.ReadyButton.Text = 'Ready?';
                app.ReadyLamp.Color = [0.65,0.65,0.65];
            end
        end

        % Cell edit callback: UITable
        function Tableeditfcn(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Value changed function: knnNumberEditField
        function knnnumberfcn(app, event)
            value = app.knnNumberEditField.Value;
            if value < 10 && value > 0
                app.processingProperties.knnnumber = value;
            else
                app.knnNumberEditField.BackgroundColor = [0.98, 0.53, 0.53] ;
                app.knnNumberEditField.Tooltip = 'Please enter a valid Positive Number less than 10';
            end
            
        end

        % Value changed function: KernelEditField
        function kernelfunfcn(app, event)
            value = app.KernelEditField.Value;
            kernels = app.processingproperties.availablekernels();
            if ismember(kernels,value)
                app.processingProperties.kernelfun = value;
            else
                app.KernelEditField.BackgroundColor = [0.98, 0.53, 0.53] ;
            end
        end

        % Value changed function: LevelEditField
        function mspcalvlfcn(app, event)
            value = app.LevelEditField.Value;
            if ~isempty(value)
            app.processingProperties.wmspca.level = value;
            else
                app.LevelEditField.BackgroundColor = [0.98, 0.53, 0.53];
            end
        end

        % Value changed function: mspcafunction
        function mspcafuncfcn(app, event)
            value = app.mspcafunction.Value;
            app.processingProperties.wmspca.wname = value ;
        end

        % Value changed function: butterlevel
        function butterlvlfcn(app, event)
            value = app.butterlevel.Value;
            if ~isempty(value)
                app.eegProperties.filterproperties.filterdegree = value;
            else
                app.butterlevel.BackgroundColor = [0.98, 0.53, 0.53];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BCIProcessor = uifigure('Visible', 'off');
            app.BCIProcessor.Name = 'BCI Processing App V 0.7.8.2';
            app.Panel = uipanel(app.BCIProcessor);
            app.DataSelectionLabel = uilabel(app.Panel);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.classifiers = uipanel(app.Panel);
            app.classifiers.Title = 'Classification Method(s):';
            app.MahalCheckBox = uicheckbox(app.classifiers);
            app.MahalCheckBox.ValueChangedFcn = createCallbackFcn(app, @mahalfcn, true);
            app.MahalCheckBox.Text = 'Mahal';
            app.MahalCheckBox.Value = true;
            app.LDACheckBox = uicheckbox(app.classifiers);
            app.LDACheckBox.ValueChangedFcn = createCallbackFcn(app, @ldcfcn, true);
            app.LDACheckBox.Text = 'LDA';
            app.LDACheckBox.Value = true;
            app.KNNCheckBox = uicheckbox(app.classifiers);
            app.KNNCheckBox.ValueChangedFcn = createCallbackFcn(app, @knnfcn, true);
            app.KNNCheckBox.Text = 'KNN';
            app.KNNCheckBox.Value = true;
            app.SVMCheckBox = uicheckbox(app.classifiers);
            app.SVMCheckBox.ValueChangedFcn = createCallbackFcn(app, @svmfcn, true);
            app.SVMCheckBox.Tooltip = {'Support Vector Machine'; ''};
            app.SVMCheckBox.Text = 'SVM';
            app.SVMCheckBox.Value = true;
            app.DCNNCheckBox = uicheckbox(app.classifiers);
            app.DCNNCheckBox.ValueChangedFcn = createCallbackFcn(app, @dcnnfcn, true);
            app.DCNNCheckBox.Enable = 'off';
            app.DCNNCheckBox.Text = 'DCNN';
            app.LSTMDNNCheckBox = uicheckbox(app.classifiers);
            app.LSTMDNNCheckBox.ValueChangedFcn = createCallbackFcn(app, @lstmdnn, true);
            app.LSTMDNNCheckBox.Enable = 'off';
            app.LSTMDNNCheckBox.Text = 'LSTMDNN';
            app.NeighbourLabel = uilabel(app.classifiers);
            app.NeighbourLabel.Enable = 'off';
            app.NeighbourLabel.Text = 'Neighbour';
            app.knnNumberEditField = uieditfield(app.classifiers, 'numeric');
            app.knnNumberEditField.Limits = [1 10];
            app.knnNumberEditField.ValueChangedFcn = createCallbackFcn(app, @knnnumberfcn, true);
            app.knnNumberEditField.Enable = 'off';
            app.knnNumberEditField.Value = 1;
            app.KernelEditFieldLabel = uilabel(app.classifiers);
            app.KernelEditFieldLabel.Enable = 'off';
            app.KernelEditFieldLabel.Text = 'Kernel';
            app.KernelEditField = uieditfield(app.classifiers, 'text');
            app.KernelEditField.ValueChangedFcn = createCallbackFcn(app, @kernelfunfcn, true);
            app.KernelEditField.Enable = 'off';
            app.KernelEditField.Tooltip = {'''rbf'' ,''polynomial'' , ''linear'''; ''; ''};
            app.KernelEditField.Value = 'rbf';
            app.KfoldCrossvalidationEditFieldLabel = uilabel(app.classifiers);
            app.KfoldCrossvalidationEditFieldLabel.Text = 'K-fold (Crossvalidation)';
            app.KfoldCrossvalidationEditField = uieditfield(app.classifiers, 'numeric');
            app.KfoldCrossvalidationEditField.Limits = [1 10];
            app.KfoldCrossvalidationEditField.ValueChangedFcn = createCallbackFcn(app, @kfoldfcn, true);
            app.KfoldCrossvalidationEditField.Value = 9;
            app.preprocess = uipanel(app.Panel);
            app.preprocess.Title = 'Pre-process:';
            app.MSPCACheckBox = uicheckbox(app.preprocess);
            app.MSPCACheckBox.ValueChangedFcn = createCallbackFcn(app, @wmspcafcn, true);
            app.MSPCACheckBox.Text = 'MSPCA';
            app.ButterworthCheckBox = uicheckbox(app.preprocess);
            app.ButterworthCheckBox.ValueChangedFcn = createCallbackFcn(app, @butterfcn, true);
            app.ButterworthCheckBox.Text = 'Butterworth';
            app.LevelEditFieldLabel = uilabel(app.preprocess);
            app.LevelEditFieldLabel.Enable = 'off';
            app.LevelEditFieldLabel.Text = 'Level';
            app.butterlevel = uieditfield(app.preprocess, 'numeric');
            app.butterlevel.Limits = [1 6];
            app.butterlevel.ValueChangedFcn = createCallbackFcn(app, @butterlvlfcn, true);
            app.butterlevel.Enable = 'off';
            app.butterlevel.Value = 1;
            app.LowEditFieldLabel = uilabel(app.preprocess);
            app.LowEditFieldLabel.Enable = 'off';
            app.LowEditFieldLabel.Text = 'Low';
            app.butterlowb = uieditfield(app.preprocess, 'numeric');
            app.butterlowb.Limits = [0 12000];
            app.butterlowb.ValueChangedFcn = createCallbackFcn(app, @lowbfcn, true);
            app.butterlowb.Enable = 'off';
            app.HighEditFieldLabel = uilabel(app.preprocess);
            app.HighEditFieldLabel.Enable = 'off';
            app.HighEditFieldLabel.Text = 'High';
            app.butterhighb = uieditfield(app.preprocess, 'numeric');
            app.butterhighb.Limits = [1 12000];
            app.butterhighb.RoundFractionalValues = 'on';
            app.butterhighb.ValueChangedFcn = createCallbackFcn(app, @highboundfcn, true);
            app.butterhighb.Enable = 'off';
            app.butterhighb.Value = 1;
            app.LevelEditFieldLabel_2 = uilabel(app.preprocess);
            app.LevelEditFieldLabel_2.Enable = 'off';
            app.LevelEditFieldLabel_2.Text = 'Level';
            app.LevelEditField = uieditfield(app.preprocess, 'numeric');
            app.LevelEditField.Limits = [1 10];
            app.LevelEditField.RoundFractionalValues = 'on';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @mspcalvlfcn, true);
            app.LevelEditField.Editable = 'off';
            app.LevelEditField.Enable = 'off';
            app.LevelEditField.Value = 1;
            app.FunctionDropDownLabel = uilabel(app.preprocess);
            app.FunctionDropDownLabel.Enable = 'off';
            app.FunctionDropDownLabel.Text = 'Function';
            app.mspcafunction = uidropdown(app.preprocess);
            app.mspcafunction.Items = {'db1', 'db2', 'db6', 'sym4'};
            app.mspcafunction.ValueChangedFcn = createCallbackFcn(app, @mspcafuncfcn, true);
            app.mspcafunction.Enable = 'off';
            app.mspcafunction.Value = 'db1';
            app.features = uipanel(app.Panel);
            app.features.Title = 'Features:';
            app.CSPCommonSpatialPatternCheckBox = uicheckbox(app.features);
            app.CSPCommonSpatialPatternCheckBox.ValueChangedFcn = createCallbackFcn(app, @cspfeaturefcn, true);
            app.CSPCommonSpatialPatternCheckBox.Text = 'CSP (Common Spatial Pattern)';
            app.FractalDimenssionHiguchiCheckBox = uicheckbox(app.features);
            app.FractalDimenssionHiguchiCheckBox.ValueChangedFcn = createCallbackFcn(app, @higuchifeaturefcn, true);
            app.FractalDimenssionHiguchiCheckBox.Text = 'Fractal Dimenssion (Higuchi)';
            app.PSDEstimationCheckBox = uicheckbox(app.features);
            app.PSDEstimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @psdfeature, true);
            app.PSDEstimationCheckBox.Tooltip = {'Power Spectral Density Estimation'};
            app.PSDEstimationCheckBox.Text = 'PSD (Estimation)';
            app.EntropyEstimationCheckBox = uicheckbox(app.features);
            app.EntropyEstimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @entropyfeature, true);
            app.EntropyEstimationCheckBox.Text = 'Entropy Estimation';
            app.HurstExponentCheckBox = uicheckbox(app.features);
            app.HurstExponentCheckBox.ValueChangedFcn = createCallbackFcn(app, @hurstfeaturefcn, true);
            app.HurstExponentCheckBox.Text = 'Hurst Exponent';
            app.PeakCheckBox = uicheckbox(app.features);
            app.PeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @peakfeaturefcn, true);
            app.PeakCheckBox.Text = 'Peak';
            app.LiapanuvExpCheckBox = uicheckbox(app.features);
            app.LiapanuvExpCheckBox.ValueChangedFcn = createCallbackFcn(app, @liapfeaturefcn, true);
            app.LiapanuvExpCheckBox.Tooltip = {'May be unstable'};
            app.LiapanuvExpCheckBox.Text = 'Liapanuv Exp.';
            app.higuchiSingleChannelCheckBox = uicheckbox(app.features);
            app.higuchiSingleChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @higsinglechfcn, true);
            app.higuchiSingleChannelCheckBox.Enable = 'off';
            app.higuchiSingleChannelCheckBox.Text = 'Single Channel';
            app.higuchiAllChannelsCheckBox = uicheckbox(app.features);
            app.higuchiAllChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @higallchfcn, true);
            app.higuchiAllChannelsCheckBox.Enable = 'off';
            app.higuchiAllChannelsCheckBox.Text = 'All Channels';
            app.higuchiCSPHiguchiCheckBox = uicheckbox(app.features);
            app.higuchiCSPHiguchiCheckBox.ValueChangedFcn = createCallbackFcn(app, @higcspfcn, true);
            app.higuchiCSPHiguchiCheckBox.Enable = 'off';
            app.higuchiCSPHiguchiCheckBox.Tooltip = {'Use CSP Projection Matrix to filter fraactal Dim.'};
            app.higuchiCSPHiguchiCheckBox.Text = 'CSP(Higuchi)';
            app.NumberofVariancerowsEditFieldLabel = uilabel(app.features);
            app.NumberofVariancerowsEditFieldLabel.Enable = 'off';
            app.NumberofVariancerowsEditFieldLabel.Text = 'Number of Variance rows';
            app.CSPvarparam = uieditfield(app.features, 'numeric');
            app.CSPvarparam.Limits = [1 4];
            app.CSPvarparam.ValueChangedFcn = createCallbackFcn(app, @cspvarfcn, true);
            app.CSPvarparam.Editable = 'off';
            app.CSPvarparam.Enable = 'off';
            app.CSPvarparam.Tooltip = {'for the entered "m" , "2m" rows will be chosen for the desiered class and the opposite class'};
            app.CSPvarparam.Value = 1;
            app.psdCompleteCheckBox = uicheckbox(app.features);
            app.psdCompleteCheckBox.ValueChangedFcn = createCallbackFcn(app, @psdcompletefcn, true);
            app.psdCompleteCheckBox.Enable = 'off';
            app.psdCompleteCheckBox.Tooltip = {'The complete diagram'};
            app.psdCompleteCheckBox.Text = 'Complete';
            app.psdTopPeakCheckBox = uicheckbox(app.features);
            app.psdTopPeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @psdtopfcn, true);
            app.psdTopPeakCheckBox.Enable = 'off';
            app.psdTopPeakCheckBox.Text = 'Top Peak';
            app.psdTop3PeaksCheckBox = uicheckbox(app.features);
            app.psdTop3PeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @psdtop3fcn, true);
            app.psdTop3PeaksCheckBox.Enable = 'off';
            app.psdTop3PeaksCheckBox.Text = 'Top 3 Peaks';
            app.ChannelsEditFieldLabel = uilabel(app.features);
            app.ChannelsEditFieldLabel.Enable = 'off';
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.cspchannelsEditField = uieditfield(app.features, 'text');
            app.cspchannelsEditField.ValueChangedFcn = createCallbackFcn(app, @cspchannelsfcn, true);
            app.cspchannelsEditField.Editable = 'off';
            app.cspchannelsEditField.Enable = 'off';
            app.mfactorLabel = uilabel(app.features);
            app.mfactorLabel.Enable = 'off';
            app.mfactorLabel.Text = 'm factor';
            app.entropymfactor = uieditfield(app.features, 'numeric');
            app.entropymfactor.Limits = [1 2];
            app.entropymfactor.RoundFractionalValues = 'on';
            app.entropymfactor.ValueChangedFcn = createCallbackFcn(app, @entropymfacfcn, true);
            app.entropymfactor.Enable = 'off';
            app.entropymfactor.Value = 1;
            app.rfactorLabel = uilabel(app.features);
            app.rfactorLabel.Enable = 'off';
            app.rfactorLabel.Text = 'r factor';
            app.entropyrfactor = uieditfield(app.features, 'numeric');
            app.entropyrfactor.Limits = [0.1 0.25];
            app.entropyrfactor.ValueChangedFcn = createCallbackFcn(app, @entropyrfacfcn, true);
            app.entropyrfactor.Enable = 'off';
            app.entropyrfactor.Value = 0.1;
            app.entropySingleChannelCheckBox = uicheckbox(app.features);
            app.entropySingleChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @entropysinglechfcn, true);
            app.entropySingleChannelCheckBox.Enable = 'off';
            app.entropySingleChannelCheckBox.Text = 'Single Channel';
            app.entropyAllChannelsCheckBox = uicheckbox(app.features);
            app.entropyAllChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @entropyallchfcn, true);
            app.entropyAllChannelsCheckBox.Enable = 'off';
            app.entropyAllChannelsCheckBox.Text = 'All Channels';
            app.hurstSingleChannelCheckBox = uicheckbox(app.features);
            app.hurstSingleChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @hurstsinglechfcn, true);
            app.hurstSingleChannelCheckBox.Enable = 'off';
            app.hurstSingleChannelCheckBox.Text = 'Single Channel';
            app.hurstAllChannelsCheckBox = uicheckbox(app.features);
            app.hurstAllChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @hurstallchfcn, true);
            app.hurstAllChannelsCheckBox.Enable = 'off';
            app.hurstAllChannelsCheckBox.Text = 'All Channels';
            app.liapSingleChannelCheckBox = uicheckbox(app.features);
            app.liapSingleChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @liapsinglechfcn, true);
            app.liapSingleChannelCheckBox.Enable = 'off';
            app.liapSingleChannelCheckBox.Text = 'Single Channel';
            app.liapAllChannelsCheckBox = uicheckbox(app.features);
            app.liapAllChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @liapallchfcn, true);
            app.liapAllChannelsCheckBox.Enable = 'off';
            app.liapAllChannelsCheckBox.Text = 'All Channels';
            app.FunctionsLabel = uilabel(app.features);
            app.FunctionsLabel.Enable = 'off';
            app.FunctionsLabel.Text = 'Function(s):';
            app.peakFunctionListBox = uilistbox(app.features);
            app.peakFunctionListBox.Items = {'Time Series', 'FFT', 'Descrete Derivative', 'Second Descrete Derivative', 'Derivative Channels', 'Average Time Serives'};
            app.peakFunctionListBox.Multiselect = 'on';
            app.peakFunctionListBox.ValueChangedFcn = createCallbackFcn(app, @peaklistfcn, true);
            app.peakFunctionListBox.Enable = 'off';
            app.peakFunctionListBox.Value = {'FFT'};
            app.selectrawfile = uipanel(app.Panel);
            app.selectrawfile.Title = 'Select File/Foldre:';
            app.AvailableSamplesDropDownLabel = uilabel(app.selectrawfile);
            app.AvailableSamplesDropDownLabel.Text = 'Available Samples';
            app.AvailableSamplesDropDown = uidropdown(app.selectrawfile);
            app.AvailableSamplesDropDown.Items = {'Safa', 'Sajad', 'none'};
            app.AvailableSamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @sampleeegfcn, true);
            app.AvailableSamplesDropDown.Value = 'none';
            app.inputfilesLabel = uilabel(app.selectrawfile);
            app.inputfilesLabel.Text = 'Input File(s):';
            app.Choosefile = uibutton(app.selectrawfile, 'push');
            app.Choosefile.ButtonPushedFcn = createCallbackFcn(app, @choosedirfcn, true);
            app.Choosefile.Text = 'Choose...';
            app.InfoButton = uibutton(app.selectrawfile, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @fileinfofcn, true);
            app.InfoButton.Enable = 'off';
            app.InfoButton.Text = 'Info';
            app.DirLamp = uilamp(app.selectrawfile);
            app.savefolderLabel = uilabel(app.selectrawfile);
            app.savefolderLabel.Text = 'Save Folder:';
            app.savefile_2 = uibutton(app.selectrawfile, 'push');
            app.savefile_2.ButtonPushedFcn = createCallbackFcn(app, @savefilefcn, true);
            app.savefile_2.Text = 'Choose...';
            app.saveDirLamp = uilamp(app.selectrawfile);
            app.BestAccuracyGauge = uigauge(app.Panel, 'circular');
            app.ClassifcatoinLabel = uilabel(app.Panel);
            app.ClassifcatoinLabel.Text = 'Classifcatoin';
            app.WhattoDoButtonGroup = uibuttongroup(app.Panel);
            app.WhattoDoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @whattodofcn, true);
            app.WhattoDoButtonGroup.Title = 'What to Do:';
            app.OnlyExtractFeatureButton = uiradiobutton(app.WhattoDoButtonGroup);
            app.OnlyExtractFeatureButton.Text = 'Only Extract Feature';
            app.OnlyExtractFeatureButton.Value = true;
            app.ClassifyAvailableFeaturesButton = uiradiobutton(app.WhattoDoButtonGroup);
            app.ClassifyAvailableFeaturesButton.Text = 'Classify Available Features';
            app.ProcessAvailableResultsButton = uiradiobutton(app.WhattoDoButtonGroup);
            app.ProcessAvailableResultsButton.Text = 'Process Available Results';
            app.AllTogetherButton = uiradiobutton(app.WhattoDoButtonGroup);
            app.AllTogetherButton.Text = 'All Together';
            app.ExtractClassifiyButton = uiradiobutton(app.WhattoDoButtonGroup);
            app.ExtractClassifiyButton.Tooltip = {'No Process'};
            app.ExtractClassifiyButton.Text = 'Extract & Classifiy ';
            app.StartLabel = uilabel(app.Panel);
            app.StartLabel.Enable = 'off';
            app.StartLabel.Text = 'Start !';
            app.turnonSwitch = uiswitch(app.Panel, 'toggle');
            app.turnonSwitch.ValueChangedFcn = createCallbackFcn(app, @turnonfcn, true);
            app.turnonSwitch.Enable = 'off';
            app.StatusLamp = uilamp(app.Panel);
            app.FileLoadingLampLabel = uilabel(app.Panel);
            app.FileLoadingLampLabel.Text = 'File Loading';
            app.FileLoadingLamp = uilamp(app.Panel);
            app.PreprocessingLampLabel = uilabel(app.Panel);
            app.PreprocessingLampLabel.Text = 'Preprocessing';
            app.PreprocessingLamp = uilamp(app.Panel);
            app.FeatureExtractionLampLabel = uilabel(app.Panel);
            app.FeatureExtractionLampLabel.Text = 'Feature Extraction';
            app.FeatureExtractionLamp = uilamp(app.Panel);
            app.ClassificationLampLabel = uilabel(app.Panel);
            app.ClassificationLampLabel.Text = 'Classification';
            app.ClassificationLamp = uilamp(app.Panel);
            app.PlotLampLabel = uilabel(app.Panel);
            app.PlotLampLabel.Text = 'Plot';
            app.PlotLamp = uilamp(app.Panel);
            app.SavingResultsLampLabel = uilabel(app.Panel);
            app.SavingResultsLampLabel.Text = 'Saving Results';
            app.SavingResultsLamp = uilamp(app.Panel);
            app.ReadyButton = uibutton(app.Panel, 'state');
            app.ReadyButton.ValueChangedFcn = createCallbackFcn(app, @readybuttfcn, true);
            app.ReadyButton.Text = 'Ready?';
            app.ReadyLamp = uilamp(app.Panel);
            app.FeaturePropertiesPanel = uipanel(app.Panel);
            app.FeaturePropertiesPanel.Title = 'Feature Properties:';
            app.ProcessingParametersButton = uibutton(app.FeaturePropertiesPanel, 'push');
            app.ProcessingParametersButton.ButtonPushedFcn = createCallbackFcn(app, @processingparamfcn, true);
            app.ProcessingParametersButton.Text = 'Processing Parameters';
            app.ProcessingparamLamp = uilamp(app.FeaturePropertiesPanel);
            app.HowtoUseFeaturesButtonGroup = uibuttongroup(app.FeaturePropertiesPanel);
            app.HowtoUseFeaturesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @modeltypefcn, true);
            app.HowtoUseFeaturesButtonGroup.Title = 'How to Use Features:';
            app.SeparatelyButton = uiradiobutton(app.HowtoUseFeaturesButtonGroup);
            app.SeparatelyButton.Text = 'Separately';
            app.SeparatelyButton.Value = true;
            app.AllTogetherButton_2 = uiradiobutton(app.HowtoUseFeaturesButtonGroup);
            app.AllTogetherButton_2.Text = 'All Together';
            app.LastBestResultsButton = uiradiobutton(app.HowtoUseFeaturesButtonGroup);
            app.LastBestResultsButton.Tooltip = {'Use last results to'; 'choose best features'};
            app.LastBestResultsButton.Text = {'Last Best Results'; ''};
            app.signaltype = uipanel(app.FeaturePropertiesPanel);
            app.signaltype.Title = 'Signal Type for:';
            app.signaltypeFastFourierTransformCheckBox = uicheckbox(app.signaltype);
            app.signaltypeFastFourierTransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @fftfcn, true);
            app.signaltypeFastFourierTransformCheckBox.Enable = 'off';
            app.signaltypeFastFourierTransformCheckBox.Text = 'Fast Fourier Transform';
            app.signaltypetimenormalCheckBox = uicheckbox(app.signaltype);
            app.signaltypetimenormalCheckBox.ValueChangedFcn = createCallbackFcn(app, @normalfcn, true);
            app.signaltypetimenormalCheckBox.Enable = 'off';
            app.signaltypetimenormalCheckBox.Text = 'time (normal)';
            app.siganltypeDescreteDerivativeCheckBox = uicheckbox(app.signaltype);
            app.siganltypeDescreteDerivativeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ddfcn, true);
            app.siganltypeDescreteDerivativeCheckBox.Enable = 'off';
            app.siganltypeDescreteDerivativeCheckBox.Text = 'Descrete Derivative';
            app.signaltypeSeconedDescreteDerivativeCheckBox = uicheckbox(app.signaltype);
            app.signaltypeSeconedDescreteDerivativeCheckBox.ValueChangedFcn = createCallbackFcn(app, @dd2fcn, true);
            app.signaltypeSeconedDescreteDerivativeCheckBox.Enable = 'off';
            app.signaltypeSeconedDescreteDerivativeCheckBox.Text = 'Seconed Descrete Derivative';
            app.signaltypeDerivativeChannelsCheckBox = uicheckbox(app.signaltype);
            app.signaltypeDerivativeChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @diffchfcn, true);
            app.signaltypeDerivativeChannelsCheckBox.Enable = 'off';
            app.signaltypeDerivativeChannelsCheckBox.Text = 'Derivative Channels';
            app.diffcheditfield = uieditfield(app.signaltype, 'text');
            app.diffcheditfield.ValueChangedFcn = createCallbackFcn(app, @diffchchannelsfcn, true);
            app.diffcheditfield.Enable = 'off';
            app.Panel_2 = uipanel(app.BCIProcessor);
            app.TabGroup = uitabgroup(app.Panel_2);
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.WhatsishappeningLabel = uilabel(app.InfoTab);
            app.WhatsishappeningLabel.Text = 'Whats is happening:';
            app.TextArea = uitextarea(app.InfoTab);
            app.TextArea.Editable = 'off';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Accuracy of ')
            xlabel(app.UIAxes, {'Window Sliding'; ''})
            ylabel(app.UIAxes, 'Accuracy %')
            app.UIAxes.XLim = [-100 300];
            app.UIAxes.YLim = [0 100];
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Accuracy%'; 'Slide'; 'Class'; 'Channel'; 'WindowLength'; 'Feature'; 'Classifier'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @Tableeditfcn, true);
            app.ProcessResults = uipanel(app.Panel_2);
            app.ProcessResults.Title = 'Process Methodes:';
            app.PlotResultsCheckBox = uicheckbox(app.ProcessResults);
            app.PlotResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotresultsfcn, true);
            app.PlotResultsCheckBox.Enable = 'off';
            app.PlotResultsCheckBox.Tooltip = {'Which Results should be Ploted'};
            app.PlotResultsCheckBox.Text = 'Plot Results';
            app.PlotFeaturesCheckBox = uicheckbox(app.ProcessResults);
            app.PlotFeaturesCheckBox.ValueChangedFcn = createCallbackFcn(app, @plotfeaturesfcn, true);
            app.PlotFeaturesCheckBox.Enable = 'off';
            app.PlotFeaturesCheckBox.Tooltip = {'What featues to be ploted'; ''};
            app.PlotFeaturesCheckBox.Text = 'Plot Features';
            app.FeaturesListBoxLabel = uilabel(app.ProcessResults);
            app.FeaturesListBoxLabel.Enable = 'off';
            app.FeaturesListBoxLabel.Text = 'Features';
            app.FeaturesListBox = uilistbox(app.ProcessResults);
            app.FeaturesListBox.Items = {'Higuchi', 'CSP', 'PSD', 'Entropy', 'Hurst Exp.', 'Liapanuv Exp.', 'Peak', ''};
            app.FeaturesListBox.Multiselect = 'on';
            app.FeaturesListBox.Enable = 'off';
            app.FeaturesListBox.Value = {'Higuchi'};
            app.ResultsListBoxLabel = uilabel(app.ProcessResults);
            app.ResultsListBoxLabel.Enable = 'off';
            app.ResultsListBoxLabel.Text = 'Results';
            app.ResultsListBox = uilistbox(app.ProcessResults);
            app.ResultsListBox.Items = {'Classification Rate', 'Sensitivity', 'Specificity', 'Kappa'};
            app.ResultsListBox.Multiselect = 'on';
            app.ResultsListBox.Enable = 'off';
            app.ResultsListBox.Value = {'Classification Rate'};
            % Show the figure after all components are created
            app.BCIProcessor.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BCI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BCIProcessor)

            % Execute the startup function
            runStartupFcn(app, @statupfcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BCIProcessor)
        end
    end
end