classdef infobutton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        eeginfoUI                       matlab.ui.Figure
        HeaderTypeButtonGroup           matlab.ui.container.ButtonGroup
        TogetherButton                  matlab.ui.control.RadioButton
        SeparateButton                  matlab.ui.control.RadioButton
        BCICompetitionIIIButton         matlab.ui.control.RadioButton
        BCICompetitionIVButton          matlab.ui.control.RadioButton
        TabGroup                        matlab.ui.container.TabGroup
        TogetherTab                     matlab.ui.container.Tab
        NoofEEGRowsEditFieldLabel       matlab.ui.control.Label
        NoofEEGRowsEditField            matlab.ui.control.NumericEditField
        TrialIdRowEditFieldLabel        matlab.ui.control.Label
        TrialIdRowEditField             matlab.ui.control.NumericEditField
        ImagineIdRowEditFieldLabel      matlab.ui.control.Label
        ImagineIdRowEditField           matlab.ui.control.NumericEditField
        NumberofFilesEditFieldLabel     matlab.ui.control.Label
        NumberofFilesEditField          matlab.ui.control.NumericEditField
        NumberofClassesLabel            matlab.ui.control.Label
        NumberofClassesEditField        matlab.ui.control.NumericEditField
        ControlClassEditFieldLabel      matlab.ui.control.Label
        ControlClassEditField           matlab.ui.control.NumericEditField
        ImagineMarkerTextAreaLabel      matlab.ui.control.Label
        ImagineMarkerTextArea           matlab.ui.control.TextArea
        TrialMarkerTextAreaLabel        matlab.ui.control.Label
        TrialMarkerTextArea             matlab.ui.control.TextArea
        ErrorRowEditFieldLabel          matlab.ui.control.Label
        ErrorRowEditField               matlab.ui.control.NumericEditField
        PauseRowEditFieldLabel          matlab.ui.control.Label
        PauseRowEditField               matlab.ui.control.NumericEditField
        ClassRowEditFieldLabel          matlab.ui.control.Label
        ClassRowEditField               matlab.ui.control.NumericEditField
        NumberofTiralsperfileEditFieldLabel  matlab.ui.control.Label
        NumberofTiralsperfileEditField  matlab.ui.control.NumericEditField
        EEGRowsEditFieldLabel           matlab.ui.control.Label
        EEGRowsEditField                matlab.ui.control.EditField
        OtherMethodsDropDownLabel       matlab.ui.control.Label
        OtherMethodsDropDown            matlab.ui.control.DropDown
        OthermethodsDropDownLabel       matlab.ui.control.Label
        OthermethodsDropDown            matlab.ui.control.DropDown
        SeparateTab                     matlab.ui.container.Tab
        TrialEditFieldLabel             matlab.ui.control.Label
        TrialEditField                  matlab.ui.control.EditField
        ChooseButton                    matlab.ui.control.Button
        ChooseButton_2                  matlab.ui.control.Button
        ImagineEditFieldLabel           matlab.ui.control.Label
        ImagineEditField                matlab.ui.control.EditField
        ChooseButton_3                  matlab.ui.control.Button
        ClassLabelsEditFieldLabel       matlab.ui.control.Label
        ClassLabelsEditField            matlab.ui.control.EditField
        BCICompetitionIIITab            matlab.ui.container.Tab
        UnderConstructionLabel_2        matlab.ui.control.Label
        BCICompetitionIVTab             matlab.ui.container.Tab
        UnderConstructionLabel          matlab.ui.control.Label
        ProceedButton                   matlab.ui.control.Button
        DataSetButtonGroup              matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        Button_6                        matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        %app.eegPropertiesinfo % Description
    end
    
    properties (Access = private)
        motherapp % Description
        eegPropertiesinfo
    end
    
    methods (Access = private)
        
        function setparams(app)
            if ~isempty(app.eegPropertiesinfo.inputmethod)
                if strcmp(app.eegPropertiesinfo.inputmethod,'onefile')
                    app.HeaderTypeButtonGroup.SelectedObject .Text = 'Together';
                    app.TabGroup.SelectedTab = app.TogetherTab ;
                elseif strcmp (app.eegPropertiesinfo.inputmethod,'different')
                    app.HeaderTypeButtonGroup.SelectedObject.Text = 'Separate';
                    app.TabGroup.SelectedTab = app.SeparateTab ;
                else
                    app.HeaderTypeButtonGroup.SelectedObject.Text = 'BCI Competition III';
                    app.TabGroup.SelectedTab = app.BCICompetitionIIITab ;
                end
                
                app.NoofEEGRowsEditField.Value = size(app.eegPropertiesinfo.channels,2);
                app.TrialIdRowEditField.Value = app.eegPropertiesinfo.trialrow;
                app.ImagineIdRowEditField.Value = app.eegPropertiesinfo.imaginerow;
                app.NumberofFilesEditField.Value = app.eegPropertiesinfo.nooffiles;
                app.ClassRowEditField.Value = app.eegPropertiesinfo.classrow ;
                app.ErrorRowEditField.Value = app.eegPropertiesinfo.registererrorrow;
                app.PauseRowEditField.Value = app.eegPropertiesinfo.registererrorrow;
                app.TrialMarkerTextArea.Value = app.eegPropertiesinfo.trialmarker;
                app.ImagineMarkerTextArea.Value = app.eegPropertiesinfo.imaginemarker;
                app.NumberofFilesEditField.Value = app.eegPropertiesinfo.nooffiles;
                app.NumberofClassesEditField.Value = app.eegPropertiesinfo.noofclass;
                app.ControlClassEditField.Value = app.eegPropertiesinfo.controlclass;
                
                app.NumberofTiralsperfileEditField.Value = app.eegPropertiesinfo.nooftrials;
                
                if ~strcmp(app.eegPropertiesinfo.sampleeeg,'none')
                    app.NoofEEGRowsEditField.Enable = false ;
                    app.TrialIdRowEditField.Enable = false ;
                    app.ImagineIdRowEditField.Enable = false ;
                    app.ClassRowEditField.Enable = false ;
                    app.ErrorRowEditField.Enable = false ;
                    app.PauseRowEditField.Enable = false ;
                    app.TrialMarkerTextArea.Enable = false ;
                    app.ImagineMarkerTextArea.Enable = false ;
                    app.ControlClassEditField.Enable = false ;
                    
                    app.NumberofTiralsperfileEditField.Enable = false ;
                end
            end
            
        end
        
        function result = checkinputvalues(app)
            app.eegPropertiesinfo.eegrows = str2num(app.EEGRowsEditField.Value);
            app.eegPropertiesinfo.trialrow = (app.TrialIdRowEditField.Value);
            app.eegPropertiesinfo.imaginerow = (app.ImagineIdRowEditField.Value);
            app.eegPropertiesinfo.nooffiles = app.NumberofFilesEditField.Value ;
            app.eegPropertiesinfo.noofclass = app.NumberofClassesEditField.Value ;
            app.eegPropertiesinfo.controlclass = app.ControlClassEditField.Value;
            app.eegPropertiesinfo.classrow = app.ClassRowEditField.Value ;
            
            if app.eegPropertiesinfo.controlclass > app.eegPropertiesinfo.noofclass
                result = false;
                text = 'Control Class must be one of the Classes.';
                addreport(app.motherapp,text);
            end
            if isempty(app.eegPropertiesinfo.trialmarker)
                app.TrialMarkerTextArea.BackgroundColor = [0.98, 0.53, 0.53];
                text = 'Trial Marker has not been Identified.';
                addreport(app.motherapp,text);
            end
            if  isempty(app.eegPropertiesinfo.imaginemarker)
                result = false ;
                app.ImagineMarkerTextArea.BackgroundColor = [0.98, 0.53, 0.53];
                text = 'Imagine Marker has not been Identified.';
                addreport(app.motherapp,text);
            end
            if any( app.eegPropertiesinfo.trialrow == app.eegPropertiesinfo.imaginerow ,...
                    app.eegPropertiesinfo.trialrow == app.eegPropertiesinfo.classrow, ...
                    app.eegPropertiesinfo.imaginerow == app.eegPropertiesinfo.classrow ,...
                    any(find(app.eegPropertiesinfo.eegrows,app.eegPropertiesinfo.trialrow)),...
                    any(find(app.eegPropertiesinfo.eegrows,app.eegPropertiesinfo.imaginerow)) ...
                    )
                
                result = false;
            else
                result = true;
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, eegProperties)

                app.motherapp = mainapp ;
                app.eegPropertiesinfo = eegProperties;  
                setparams(app);
        end

        % Selection changed function: HeaderTypeButtonGroup
        function headertypefcn(app, event)
            selectedButton = app.HeaderTypeButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Together'
                app.TabGroup.SelectedTab = app.TogetherTab ;
                app.eegPropertiesinfo.inputmethod = 'onefile';
                case 'Separate'
                app.TabGroup.SelectedTab = app.SeparateTab;
                app.eegPropertiesinfo.inputmethod = 'different';
                case 'BCI Competition III'
                app.TabGroup.SelectedTab = app.BCICompetitionIIITab ;
            end
        end

        % Value changed function: NoofEEGRowsEditField
        function eegrowsfcn(app, event)
            
        end

        % Value changed function: TrialIdRowEditField
        function trialrowfcn(app, event)
            value = app.TrialIdRowEditField.Value;
            app.eegPropertiesinfo.trialrow = value;
            
        end

        % Value changed function: ImagineIdRowEditField
        function imaginerow(app, event)
            value = app.ImagineIdRowEditField.Value;
            app.eegPropertiesinfo.imaginerow = value;
        end

        % Value changed function: NumberofFilesEditField
        function nooffilesfcn(app, event)
            value = app.NumberofFilesEditField.Value;
            app.eegPropertiesinfo.nooffiles = value;
        end

        % Value changed function: ClassRowEditField
        function classrowfcn(app, event)
            value = app.ClassRowEditField.Value;
            app.eegPropertiesinfo.classrow = value;
        end

        % Value changed function: ErrorRowEditField
        function errorrowfcn(app, event)
            value = app.ErrorRowEditField.Value;
            if value == 0
                app.eegPropertiesinfo.registererrorrow = [];
            else
                 app.eegPropertiesinfo.registererrorrow = value;
            end
           
        end

        % Value changed function: PauseRowEditField
        function pauserowfcn(app, event)
            value = app.PauseRowEditField.Value;
            if value == 0
                app.eegPropertiesinfo.registererrorrow = [];
            else
                app.eegPropertiesinfo.registererrorrow = value;
            end
            
        end

        % Value changed function: TrialMarkerTextArea
        function trialmarkerfcn(app, event)
            value = app.TrialMarkerTextArea.Value;
            
            if value
                app.eegPropertiesinfo.trialmarker = value;
            else
                app.TrialMarkerTextArea.BackgroundColor = [0.98, 0.53, 0.53];
            end
            
        end

        % Value changed function: ImagineMarkerTextArea
        function imaginemarkerfcn(app, event)
            value = app.ImagineMarkerTextArea.Value;
            if value
                app.eegPropertiesinfo.imaginemarker = value;
            else
                app.ImagineMarkerTextAreaLabel.BackgroundColor = [0.98, 0.53, 0.53];
            end
        end

        % Value changed function: NumberofClassesEditField
        function noofclassfcn(app, event)
            value = app.NumberofClassesEditField.Value;
            app.eegPropertiesinfo.noofclass = value;
        end

        % Value changed function: ControlClassEditField
        function controlclassfcn(app, event)
            value = app.ControlClassEditField.Value;
            app.eegPropertiesinfo.controlclass = value;
            
        end

        % Callback function
        function samplingratefcn(app, event)
   
        end

        % Value changed function: NumberofTiralsperfileEditField
        function nooftrials(app, event)
            value = app.NumberofTiralsperfileEditField.Value;
            app.eegPropertiesinfo.nooftrials = value;
        end

        % Button pushed function: ProceedButton
        function proceedbutton(app, event)
            if  checkinputvalues(app)
                getinfopageapp(app.motherapp,app.eegPropertiesinfo);
                delete(app);
            else
                app.ProceedButton.FontColor = [1 , 0 ,0];
                app.ProceedButton.Tooltip = 'The entered data is not correct!';

            end
            
            
        end

        % Value changed function: EEGRowsEditField
        function eegrowsfcn2(app, event)
            value = app.EEGRowsEditField_2.Value;
            if value
                app.eegPropertiesinfo.eegrows = str2num(value);
                app.NoofEEGRowsEditField.Value = size(value,2);
                app.EEGRowsEditField.BackgroundColor = [0.78,0.97,0.72];
            else
               app.EEGRowsEditField.BackgroundColor = [0.98, 0.53, 0.53];
               app.EEGRowsEditField.Tooltip = 'Sample: 2:65 or [1,2,3,12,14,15]';
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.eeginfoUI = uifigure('Visible', 'off');
            app.eeginfoUI.Name = 'UI Figure';
            app.HeaderTypeButtonGroup = uibuttongroup(app.eeginfoUI);
            app.HeaderTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @headertypefcn, true);
            app.HeaderTypeButtonGroup.Title = 'Header Type:';
            app.TogetherButton = uiradiobutton(app.HeaderTypeButtonGroup);
            app.TogetherButton.Text = 'Together';
            app.TogetherButton.Value = true;
            app.SeparateButton = uiradiobutton(app.HeaderTypeButtonGroup);
            app.SeparateButton.Text = 'Separate';
            app.BCICompetitionIIIButton = uiradiobutton(app.HeaderTypeButtonGroup);
            app.BCICompetitionIIIButton.Enable = 'off';
            app.BCICompetitionIIIButton.Text = 'BCI Competition III';
            app.BCICompetitionIVButton = uiradiobutton(app.HeaderTypeButtonGroup);
            app.BCICompetitionIVButton.Enable = 'off';
            app.BCICompetitionIVButton.Text = {'BCI Competition IV'; ''};
            app.TabGroup = uitabgroup(app.eeginfoUI);
            app.TogetherTab = uitab(app.TabGroup);
            app.TogetherTab.Title = 'Together';
            app.NoofEEGRowsEditFieldLabel = uilabel(app.TogetherTab);
            app.NoofEEGRowsEditFieldLabel.Text = 'No of EEG Rows:';
            app.NoofEEGRowsEditField = uieditfield(app.TogetherTab, 'numeric');
            app.NoofEEGRowsEditField.Limits = [1 300];
            app.NoofEEGRowsEditField.ValueChangedFcn = createCallbackFcn(app, @eegrowsfcn, true);
            app.NoofEEGRowsEditField.Editable = 'off';
            app.NoofEEGRowsEditField.Tooltip = {'number of rows that you want'};
            app.NoofEEGRowsEditField.Value = 16;
            app.TrialIdRowEditFieldLabel = uilabel(app.TogetherTab);
            app.TrialIdRowEditFieldLabel.Text = 'Trial Id Row:';
            app.TrialIdRowEditField = uieditfield(app.TogetherTab, 'numeric');
            app.TrialIdRowEditField.Limits = [1 300];
            app.TrialIdRowEditField.ValueChangedFcn = createCallbackFcn(app, @trialrowfcn, true);
            app.TrialIdRowEditField.Tooltip = {'which row denotes to the marker for starting a new trial'; ''};
            app.TrialIdRowEditField.Value = 1;
            app.ImagineIdRowEditFieldLabel = uilabel(app.TogetherTab);
            app.ImagineIdRowEditFieldLabel.Text = 'Imagine Id Row:';
            app.ImagineIdRowEditField = uieditfield(app.TogetherTab, 'numeric');
            app.ImagineIdRowEditField.Limits = [1 300];
            app.ImagineIdRowEditField.ValueChangedFcn = createCallbackFcn(app, @imaginerow, true);
            app.ImagineIdRowEditField.Value = 1;
            app.NumberofFilesEditFieldLabel = uilabel(app.TogetherTab);
            app.NumberofFilesEditFieldLabel.Text = 'Number of Files:';
            app.NumberofFilesEditField = uieditfield(app.TogetherTab, 'numeric');
            app.NumberofFilesEditField.Limits = [1 20];
            app.NumberofFilesEditField.ValueChangedFcn = createCallbackFcn(app, @nooffilesfcn, true);
            app.NumberofFilesEditField.Value = 1;
            app.NumberofClassesLabel = uilabel(app.TogetherTab);
            app.NumberofClassesLabel.Text = {'Number of Classes:'; ''};
            app.NumberofClassesEditField = uieditfield(app.TogetherTab, 'numeric');
            app.NumberofClassesEditField.Limits = [2 10];
            app.NumberofClassesEditField.ValueChangedFcn = createCallbackFcn(app, @noofclassfcn, true);
            app.NumberofClassesEditField.Tooltip = {'Number of Classes in a file'};
            app.NumberofClassesEditField.Value = 5;
            app.ControlClassEditFieldLabel = uilabel(app.TogetherTab);
            app.ControlClassEditFieldLabel.Text = 'Control Class:';
            app.ControlClassEditField = uieditfield(app.TogetherTab, 'numeric');
            app.ControlClassEditField.Limits = [0 20];
            app.ControlClassEditField.ValueChangedFcn = createCallbackFcn(app, @controlclassfcn, true);
            app.ControlClassEditField.Value = 5;
            app.ImagineMarkerTextAreaLabel = uilabel(app.TogetherTab);
            app.ImagineMarkerTextAreaLabel.Text = 'Imagine Marker:';
            app.ImagineMarkerTextArea = uitextarea(app.TogetherTab);
            app.ImagineMarkerTextArea.ValueChangedFcn = createCallbackFcn(app, @imaginemarkerfcn, true);
            app.TrialMarkerTextAreaLabel = uilabel(app.TogetherTab);
            app.TrialMarkerTextAreaLabel.Text = 'Trial Marker:';
            app.TrialMarkerTextArea = uitextarea(app.TogetherTab);
            app.TrialMarkerTextArea.ValueChangedFcn = createCallbackFcn(app, @trialmarkerfcn, true);
            app.ErrorRowEditFieldLabel = uilabel(app.TogetherTab);
            app.ErrorRowEditFieldLabel.Text = 'Error Row:';
            app.ErrorRowEditField = uieditfield(app.TogetherTab, 'numeric');
            app.ErrorRowEditField.Limits = [0 300];
            app.ErrorRowEditField.ValueChangedFcn = createCallbackFcn(app, @errorrowfcn, true);
            app.PauseRowEditFieldLabel = uilabel(app.TogetherTab);
            app.PauseRowEditFieldLabel.Text = 'Pause Row:';
            app.PauseRowEditField = uieditfield(app.TogetherTab, 'numeric');
            app.PauseRowEditField.Limits = [0 300];
            app.PauseRowEditField.ValueChangedFcn = createCallbackFcn(app, @pauserowfcn, true);
            app.ClassRowEditFieldLabel = uilabel(app.TogetherTab);
            app.ClassRowEditFieldLabel.Text = 'Class Row:';
            app.ClassRowEditField = uieditfield(app.TogetherTab, 'numeric');
            app.ClassRowEditField.Limits = [1 300];
            app.ClassRowEditField.ValueChangedFcn = createCallbackFcn(app, @classrowfcn, true);
            app.ClassRowEditField.Value = 1;
            app.NumberofTiralsperfileEditFieldLabel = uilabel(app.TogetherTab);
            app.NumberofTiralsperfileEditFieldLabel.Text = 'Number of Tirals per file:';
            app.NumberofTiralsperfileEditField = uieditfield(app.TogetherTab, 'numeric');
            app.NumberofTiralsperfileEditField.Limits = [1 300];
            app.NumberofTiralsperfileEditField.ValueChangedFcn = createCallbackFcn(app, @nooftrials, true);
            app.NumberofTiralsperfileEditField.Tooltip = {'Number of all trials combined in a single file'};
            app.NumberofTiralsperfileEditField.Value = 45;
            app.EEGRowsEditFieldLabel = uilabel(app.TogetherTab);
            app.EEGRowsEditFieldLabel.Text = 'EEG Rows:';
            app.EEGRowsEditField = uieditfield(app.TogetherTab, 'text');
            app.EEGRowsEditField.ValueChangedFcn = createCallbackFcn(app, @eegrowsfcn2, true);
            app.EEGRowsEditField.Tooltip = {'Please enter a 1xn vector or all the rows containing EEG'};
            app.EEGRowsEditField.Value = '2:17';
            app.OtherMethodsDropDownLabel = uilabel(app.TogetherTab);
            app.OtherMethodsDropDownLabel.Text = 'Other Methods:';
            app.OtherMethodsDropDown = uidropdown(app.TogetherTab);
            app.OtherMethodsDropDown.Items = {'Eye Blink', ''};
            app.OtherMethodsDropDown.Value = 'Eye Blink';
            app.OthermethodsDropDownLabel = uilabel(app.TogetherTab);
            app.OthermethodsDropDownLabel.Text = 'Other methods:';
            app.OthermethodsDropDown = uidropdown(app.TogetherTab);
            app.OthermethodsDropDown.Items = {'No Imagine'};
            app.OthermethodsDropDown.Value = 'No Imagine';
            app.SeparateTab = uitab(app.TabGroup);
            app.SeparateTab.Title = 'Separate';
            app.TrialEditFieldLabel = uilabel(app.SeparateTab);
            app.TrialEditFieldLabel.Text = 'Trial ';
            app.TrialEditField = uieditfield(app.SeparateTab, 'text');
            app.ChooseButton = uibutton(app.SeparateTab, 'push');
            app.ChooseButton.Text = 'Choose ...';
            app.ChooseButton_2 = uibutton(app.SeparateTab, 'push');
            app.ChooseButton_2.Text = 'Choose ...';
            app.ImagineEditFieldLabel = uilabel(app.SeparateTab);
            app.ImagineEditFieldLabel.Text = 'Imagine';
            app.ImagineEditField = uieditfield(app.SeparateTab, 'text');
            app.ChooseButton_3 = uibutton(app.SeparateTab, 'push');
            app.ChooseButton_3.Text = 'Choose ...';
            app.ClassLabelsEditFieldLabel = uilabel(app.SeparateTab);
            app.ClassLabelsEditFieldLabel.Text = 'Class Labels';
            app.ClassLabelsEditField = uieditfield(app.SeparateTab, 'text');
            app.BCICompetitionIIITab = uitab(app.TabGroup);
            app.BCICompetitionIIITab.Title = 'BCI Competition III';
            app.UnderConstructionLabel_2 = uilabel(app.BCICompetitionIIITab);
            app.UnderConstructionLabel_2.Text = 'Under Construction';
            app.BCICompetitionIVTab = uitab(app.TabGroup);
            app.BCICompetitionIVTab.Title = 'BCI Competition IV';
            app.UnderConstructionLabel = uilabel(app.BCICompetitionIVTab);
            app.UnderConstructionLabel.Text = 'Under Construction';
            app.ProceedButton = uibutton(app.eeginfoUI, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @proceedbutton, true);
            app.ProceedButton.Text = 'Proceed';
            app.DataSetButtonGroup = uibuttongroup(app.eeginfoUI);
            app.DataSetButtonGroup.Title = 'Data Set:';
            app.Button = uiradiobutton(app.DataSetButtonGroup);
            app.Button.Enable = 'off';
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.DataSetButtonGroup);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.DataSetButtonGroup);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.DataSetButtonGroup);
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '4';
            app.Button_5 = uiradiobutton(app.DataSetButtonGroup);
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '5';
            app.Button_6 = uiradiobutton(app.DataSetButtonGroup);
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '6';
            % Show the figure after all components are created
            app.eeginfoUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = infobutton(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.eeginfoUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.eeginfoUI)
        end
    end
end